classdef ItalyGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SymulacjaWochUIFigure           matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        RozmiarplanszyEditFieldLabel    matlab.ui.control.Label
        BoardSize                       matlab.ui.control.NumericEditField
        LiczbaiteracjiEditFieldLabel    matlab.ui.control.Label
        NumIterations                   matlab.ui.control.NumericEditField
        PublicProtection                matlab.ui.control.CheckBox
        SelfProtection                  matlab.ui.control.CheckBox
        Label1                          matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BrakOchronyTab                  matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel  matlab.ui.control.Label
        p_none_infByInf                 matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel  matlab.ui.control.Label
        p_none_infBySick                matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel  matlab.ui.control.Label
        p_none_qBySick                  matlab.ui.control.NumericEditField
        TylkopublTab                    matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_2  matlab.ui.control.Label
        p_pub_infByInf                  matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_2  matlab.ui.control.Label
        p_pub_infBySick                 matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel_2  matlab.ui.control.Label
        p_pub_qBySick                   matlab.ui.control.NumericEditField
        TylkoindywidualnaTab            matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_3  matlab.ui.control.Label
        p_self_infByInf                 matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_3  matlab.ui.control.Label
        p_self_infBySick                matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel_3  matlab.ui.control.Label
        p_self_qBySick                  matlab.ui.control.NumericEditField
        ObieTab                         matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_4  matlab.ui.control.Label
        p_both_infByInf                 matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_4  matlab.ui.control.Label
        p_both_infBySick                matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel_4  matlab.ui.control.Label
        p_both_qBySick                  matlab.ui.control.NumericEditField
        PocztkowaliczbazaraonychLabel   matlab.ui.control.Label
        StartInfectedCnt                matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        WkwarantannieTab                matlab.ui.container.Tab
        PrawdbyciazdrowymLabel          matlab.ui.control.Label
        p_quarantine_healthy            matlab.ui.control.NumericEditField
        PrawdwyzdrowieniajelimaobjawyLabel  matlab.ui.control.Label
        p_quarantine_recovered          matlab.ui.control.NumericEditField
        PrawdhospitalizacjiLabel        matlab.ui.control.Label
        p_quarantine_hospital           matlab.ui.control.NumericEditField
        Label3                          matlab.ui.control.Label
        ZaraeniTab                      matlab.ui.container.Tab
        PrawdwyzdrowieniaprzejsciabezobjawowegoLabel  matlab.ui.control.Label
        p_infected_recovered            matlab.ui.control.NumericEditField
        PrawdzachorowaniazobjawamiLabel  matlab.ui.control.Label
        p_infected_infectedSick         matlab.ui.control.NumericEditField
        ChorzyTab                       matlab.ui.container.Tab
        PrawdkwarantannyEditFieldLabel  matlab.ui.control.Label
        p_infectedSick_quarantine       matlab.ui.control.NumericEditField
        PrawdhospitalizacjiEditFieldLabel  matlab.ui.control.Label
        p_infectedSick_hospital         matlab.ui.control.NumericEditField
        PrawdwyzdrowienaLabel           matlab.ui.control.Label
        p_infectedSick_recovered        matlab.ui.control.NumericEditField
        PrawdmierciEditFieldLabel       matlab.ui.control.Label
        p_infectedSick_dead             matlab.ui.control.NumericEditField
        HospitalizowaniTab              matlab.ui.container.Tab
        PrawdzetonieCOVID19EditFieldLabel  matlab.ui.control.Label
        p_hospital_healthy              matlab.ui.control.NumericEditField
        PrawdwyzdrowieniaEditField_2Label  matlab.ui.control.Label
        p_hospital_recovered_lackOfVents  matlab.ui.control.NumericEditField
        PrawdmierciEditField_3Label     matlab.ui.control.Label
        p_hospital_dead_lackOfVents     matlab.ui.control.NumericEditField
        ChorzynacoinnegoTab             matlab.ui.container.Tab
        PrawdwyzdrowieniaLabel          matlab.ui.control.Label
        p_sick_healthy                  matlab.ui.control.NumericEditField
        PrawdkwarantannyLabel           matlab.ui.control.Label
        p_sick_quarantine               matlab.ui.control.NumericEditField
        Label2                          matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        LiveTab                         matlab.ui.container.Tab
        LivePlot                        matlab.ui.control.UIAxes
        ZdrowyLamp_5Label               matlab.ui.control.Label
        ZdrowyLamp_5                    matlab.ui.control.Lamp
        ZaraonyLampLabel                matlab.ui.control.Label
        ZaraonyLamp                     matlab.ui.control.Lamp
        ChoryLampLabel                  matlab.ui.control.Label
        ChoryLamp                       matlab.ui.control.Lamp
        HospitalizowanyLampLabel        matlab.ui.control.Label
        HospitalizowanyLamp             matlab.ui.control.Lamp
        ChorynieCov19LampLabel          matlab.ui.control.Label
        ChorynieCov19Lamp               matlab.ui.control.Lamp
        WyzdrowiaLampLabel              matlab.ui.control.Label
        WyzdrowiaLamp                   matlab.ui.control.Lamp
        ZmarLampLabel                   matlab.ui.control.Label
        ZmarLamp                        matlab.ui.control.Lamp
        KwarantannaLampLabel            matlab.ui.control.Label
        KwarantannaLamp                 matlab.ui.control.Lamp
        RozkadTab                       matlab.ui.container.Tab
        DistributionPlot                matlab.ui.control.UIAxes
        GeneratePlotsButton             matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
    end

    
    properties (Constant)
        % definicje stanów
        HEALTHY = 1;
        IN_QUARANTINE = 2;
        INFECTED = 3;
        SICK = 4;
        INFECTED_SICK = 5;
        IN_HOSPITAL = 6;
        RECOVERED = 7;
        DEAD = 8;
    end
    
    properties (Access = private)
            cnt_healthy;
            cnt_quarantine;
            cnt_infected;
            cnt_sick;
            cnt_infected_sick;
            cnt_hospital;
            cnt_recovered;
            cnt_dead;
    end
    
    properties (Access = private)
        CancelPressed = 0 % Sets to 1 when user cancelled simulation
        CallingApp % Reference to main menu app
        
        CurrentIt;
    end
    
    methods (Access = private)
        
        function [cnt] = countNeighbours(~, board, state, row, col, n)
            if ~exist('n','var')
                n = 2;
            end
            
            [height, width] = size(board);
            if ((row > n && row < height-n) && (col > n && col < width-n))
                neighbors = board((row-n):(row+n), (col-n):(col+n));
                cnt = sum(neighbors(:) == state);
            else
                cnt = 0;
            end
        end
        
        function drawPlots(app, n)

            % Wykresy
            f1 = figure(1);
            f2 = figure(2);
            set(f1, 'Name', 'Wykresy');
            set(f2, 'Name', 'Rozklad stanow');
            set(f1, 'Position',  [100, 100, 1200, 800]);
            set(f2, 'Position',  [100, 100, 1200, 800]);
            
            figure(f1);

            ax = subplot(2,3,1);
            bar(ax, 1:n,app.cnt_healthy(1:n));
            xlabel('Iteracja');
            title('Healthy');
            
            ax = subplot(2,3,4);
            bar(ax, 1:n,app.cnt_infected(1:n) + app.cnt_infected_sick(1:n), 'y');
            xlabel('Iteracja');
            title('Infected (+ sick for COVID)');
            
            ax = subplot(2,3,2);
            bar(ax, 1:n,app.cnt_recovered(1:n), 'g');
            xlabel('Iteracja');
            title('Recovered');
            
            ax = subplot(2,3,5);
            bar(ax, 1:n,app.cnt_dead(1:n), 'r');
            xlabel('Iteracja');
            title('Dead');
            
            ax = subplot(2,3,3);
            bar(ax, 1:n,app.cnt_quarantine(1:n), 'm');
            xlabel('Iteracja');
            title('In Quarantine');
            
            ax = subplot(2,3,6);
            bar(ax, 1:n,app.cnt_hospital(1:n), 'c');
            xlabel('Iteracja');
            title('In Hospital');
            
            % saveas(gcf,'Wykresy.png');
            
            % Rozklad stanow
            figure(f2);
            drawDistributionPlot(app, n, gca);
            % saveas(gcf,'Rozklad.png');
        end
        
        function drawDistributionPlot(app, n, parent)
                            data = zeros(n,8);
                data(:,1)=app.cnt_healthy(1:n);
                data(:,2)=app.cnt_infected(1:n);
                data(:,3)=app.cnt_quarantine(1:n);
                data(:,4)=app.cnt_sick(1:n);
                data(:,5)=app.cnt_infected_sick(1:n);
                data(:,6)=app.cnt_hospital(1:n);
                data(:,7)=app.cnt_recovered(1:n);
                data(:,8)=app.cnt_dead(1:n);
            
            h = bar(data, 'stacked', 'Parent', parent);
            h(1).FaceColor = [0 0.4470 0.7410]; % healthy - blue
            h(2).FaceColor = [0.9290 0.6940 0.1250]; % infected - yellow
            h(3).FaceColor = [0.4940 0.1840 0.5560]; % quarantine - purple
            h(4).FaceColor = [0.1 0.1 0.1]; % sick - non covid - gray
            h(5).FaceColor = [0.8500 0.3250 0.0980]; % infected & sick - orange
            h(6).FaceColor = [0.3010 0.7450 0.9330]; % hospital - cyan
            h(7).FaceColor = [0.4660 0.6740 0.1880]; % recovered - green
            h(8).FaceColor = [0.8350 0.0780 0.1840]; % dead - red
            %
            %                 h.CData = ['b';'y';'m';'w';'k';'c';'g';'r'];
            
            h = legend(parent, 'Healthy', 'Infected', 'In quarantine', 'Sick (non COVID)',...
                'Infected & sick', 'In hospital', 'Recovered', 'Dead');
            set(h, 'Location', 'southwest');
        end
        
        function drawBoard_updadeStats(app, board, n, boardSize)
            
                healthy_ppl = board==app.HEALTHY;
                quarantine_ppl = board==app.IN_QUARANTINE;
                infected_ppl = board==app.INFECTED;
                sick_ppl = board==app.SICK;
                infected_sick_ppl = board==app.INFECTED_SICK;
                in_hospital_ppl = board==app.IN_HOSPITAL;
                recovered_ppl = board==app.RECOVERED;
                dead_ppl = board==app.DEAD;
                
                app.cnt_healthy(n) = sum(healthy_ppl(:));
                app.cnt_quarantine(n) = sum(quarantine_ppl(:));
                app.cnt_infected(n) = sum(infected_ppl(:));
                app.cnt_sick(n) = sum(sick_ppl(:));
                app.cnt_infected_sick(n) = sum(infected_sick_ppl(:));
                app.cnt_hospital(n) = sum(in_hospital_ppl(:));
                app.cnt_recovered(n) = sum(recovered_ppl(:));
                app.cnt_dead(n) = sum(dead_ppl(:));
                
                boardR = uint8(zeros(boardSize, boardSize));
                boardG = uint8(zeros(boardSize, boardSize));
                boardB = uint8(zeros(boardSize, boardSize));
                
                % White
                boardR(healthy_ppl) = 255;
                boardG(healthy_ppl) = 255;
                boardB(healthy_ppl) = 255;
                
                % Purple
                boardR(quarantine_ppl) = 152;
                boardG(quarantine_ppl) = 51;
                boardB(quarantine_ppl) = 255;
                
                % Orange
                boardR(infected_ppl) = 255;
                boardG(infected_ppl) = 128;
                boardB(infected_ppl) = 0;
                
                % Yellow
                boardR(sick_ppl) = 255;
                boardG(sick_ppl) = 255;
                boardB(sick_ppl) = 0;
                
                % Red
                boardR(infected_sick_ppl) = 255;
                boardG(infected_sick_ppl) = 0;
                boardB(infected_sick_ppl) = 0;
                
                % Cyan
                boardR(in_hospital_ppl) = 0;
                boardG(in_hospital_ppl) = 255;
                boardB(in_hospital_ppl) = 255;
                
                % Green
                boardR(recovered_ppl) = 0;
                boardG(recovered_ppl) = 255;
                boardB(recovered_ppl) = 0;
                
                % Black
                boardR(dead_ppl) = 0;
                boardG(dead_ppl) = 0;
                boardB(dead_ppl) = 0;
                
                boardImg = cat(3, boardR, boardG, boardB);
                
                title(app.LivePlot, ['Iteracja ' num2str(n)]);
                I = imshow(boardImg, 'Parent', app.LivePlot, ...
                    'XData', [1 app.LivePlot.Position(3)], ...
                    'YData', [1 app.LivePlot.Position(4)]);
                
                app.LivePlot.XLim = [0 I.XData(2)];
                app.LivePlot.YLim = [0 I.YData(2)];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             app.CallingApp = mainApp;
            
            title(app.LivePlot, 'Live plot');
            xlabel(app.LivePlot, []);
            ylabel(app.LivePlot, []);
            app.LivePlot.XAxis.TickLabels = {};
            app.LivePlot.YAxis.TickLabels = {};
            
            xlabel(app.DistributionPlot, 'Iteracja');
            
            
            %
            %             h = zeros(8, 1);  hold on
            %             h(1) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor','w', 'Parent', app.UIAxes);
            %             h(2) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor',[152/255, 51/255, 1.0], 'Parent', app.UIAxes);
            %             h(3) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor',[1.0, 0.5, 0], 'Parent', app.UIAxes);
            %             h(4) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor','y', 'Parent', app.UIAxes);
            %             h(5) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor','r', 'Parent', app.UIAxes);
            %             h(6) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor','c', 'Parent', app.UIAxes);
            %             h(7) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor','g', 'Parent', app.UIAxes);
            %             h(8) = plot(NaN,NaN,'Color','k','LineStyle','none','Marker','s','MarkerFaceColor','k', 'Parent', app.UIAxes);
            %             hold off;
            % %             for k=1:8
            % %                 set(h(k), 'Parent', app.UIAxes);
            % %             end
            %
            %             h = legend(app.UIAxes, 'Healthy','In Quarantine','Infected','Sick',...
            %                 'Infected and Sick', 'In Hospital', 'Recovered', 'Dead');
            %             %                     set(h, 'Orientation', 'horizontal');
            %             %                     set(h, 'Location', 'SouthOutside');
            %             h.Position(1) = 0.5 - h.Position(3)/2;
            %             h.Position(2) = 0.5 - h.Position(4)/2;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.CancelPressed = 0;
            app.GeneratePlotsButton.Enable = 0;
            app.StartButton.Enable = 0;
            app.StartButton.Text = 'Czekaj...';
            app.CancelButton.Enable = 1;
            
            app.StatusLamp.Color = [1 1 0];
            app.StatusLabel.Text = 'Symulacja w toku...';
            app.StatusLamp.Enable = 1;
            
            iterations = app.NumIterations.Value;
            startingInfected = app.StartInfectedCnt.Value;
            
            selfProtectionEnabled = app.SelfProtection.Value;       % Czy zarzadzono osobista ochrone? (maseczki, rekawice...)
            publicProtectionEnabled = app.PublicProtection.Value;     % Czy zarzadzono ochrone publiczna? (dezynfekcja, ograniczenia przemieszczania się)


            % Prawdopodobienstwa przejscia stanow - p(X,Y) = prawdop. przejscia X -> Y
            p = zeros(8,8);
            p(app.IN_QUARANTINE,app.HEALTHY)                     = app.p_quarantine_healthy.Value; %70;
            p(app.IN_QUARANTINE,app.RECOVERED)                   = app.p_quarantine_recovered.Value; %25;
            p(app.IN_QUARANTINE,app.IN_HOSPITAL)                    = app.p_quarantine_hospital.Value; %5;
            p(app.SICK,app.IN_QUARANTINE)                        = app.p_sick_quarantine.Value; %5;
            p(app.SICK,app.HEALTHY)                           = app.p_sick_healthy.Value; %94;
            p(app.INFECTED, app.INFECTED_SICK)                  = app.p_infected_infectedSick.Value; %20;
            p(app.INFECTED, app.RECOVERED)                     = app.p_infected_recovered.Value; %20;
            p(app.INFECTED_SICK, app.IN_QUARANTINE)                = app.p_infectedSick_quarantine.Value; %40;
            p(app.INFECTED_SICK, app.IN_HOSPITAL)                  = app.p_infectedSick_hospital.Value; %20;
            p(app.INFECTED_SICK, app.RECOVERED)                 = app.p_infectedSick_recovered.Value; %29;
            p(app.INFECTED_SICK, app.DEAD)                      = app.p_infectedSick_dead.Value; %1;
            p(app.IN_HOSPITAL, app.DEAD) = app.p_hospital_dead_lackOfVents.Value; %50
            p(app.IN_HOSPITAL, app.RECOVERED) = app.p_hospital_recovered_lackOfVents.Value; %49
            p(app.IN_HOSPITAL, app.HEALTHY) = app.p_hospital_healthy.Value; %1;
            
            % Koniec ustawien %%%%
            
            app.cnt_healthy = zeros(1, iterations);
            app.cnt_quarantine = zeros(1, iterations);
            app.cnt_infected = zeros(1, iterations);
            app.cnt_sick  = zeros(1, iterations);
            app.cnt_infected_sick = zeros(1, iterations);
            app.cnt_hospital = zeros(1, iterations);
            app.cnt_recovered = zeros(1, iterations);
            app.cnt_dead = zeros(1, iterations);
            
            boardSize = app.BoardSize.Value + 4;
            board = uint8(ones(boardSize,boardSize));
            ventsCnt = boardSize*boardSize * 0.001;
            
            if (~selfProtectionEnabled && ~publicProtectionEnabled)
                pHealthy_infected_byInfected        = app.p_none_infByInf.Value; %40;
                pHealthy_infected_byInfectedSick    = app.p_none_infBySick.Value; %60;
                pHealthy_quarantine_byInfectedSick  = app.p_none_qBySick.Value; %1;
                
            elseif (selfProtectionEnabled && ~publicProtectionEnabled)
                pHealthy_infected_byInfected        = app.p_self_infByInf.Value; %15;
                pHealthy_infected_byInfectedSick    = app.p_self_infBySick.Value; %23;
                pHealthy_quarantine_byInfectedSick  = app.p_self_qBySick.Value; %5;
                
            elseif (~selfProtectionEnabled && publicProtectionEnabled)
                pHealthy_infected_byInfected        = app.p_pub_infByInf.Value; %30;
                pHealthy_infected_byInfectedSick    = app.p_pub_infBySick.Value; %40;
                pHealthy_quarantine_byInfectedSick  = app.p_pub_qBySick.Value; %50;
                
            elseif (selfProtectionEnabled && publicProtectionEnabled)
                pHealthy_infected_byInfected        = app.p_both_infByInf.Value; %12;
                pHealthy_infected_byInfectedSick    = app.p_both_infBySick.Value; %19;
                pHealthy_quarantine_byInfectedSick  = app.p_both_qBySick.Value; %70;
            end

            
            for n=1:startingInfected
                x_start = randi(boardSize);
                y_start = randi(boardSize);
                board(y_start, x_start) = app.INFECTED;
            end
            
            for n=1:iterations
                app.CurrentIt = n;
                
                drawBoard_updadeStats(app, board, n, boardSize);
                if(n > 1)
                    drawDistributionPlot(app, n, app.DistributionPlot);
                end

                drawnow;

                if (app.cnt_infected(n) == 0)
                    break
                end
                
                if (app.cnt_hospital(n) > ventsCnt)
                    pHospitalDead = p(app.IN_HOSPITAL,app.DEAD);
                    pHospitalRecovered = p(app.IN_HOSPITAL,app.RECOVERED);
                else
                    pHospitalDead = p(app.IN_HOSPITAL,app.DEAD) - 30;
                    pHospitalRecovered = p(app.IN_HOSPITAL,app.DEAD) + 30;
                end
                
                boardNext = board;
                current_probability = randi(101,boardSize);

                for y=1:boardSize
                    for x=1:boardSize
                        switch(board(y,x))
                            
                            case app.HEALTHY
                                if (current_probability(y,x) == 30)
                                    boardNext(y,x) = app.SICK;
                                end
                                if (countNeighbours(app,board, app.INFECTED_SICK, y, x) > 0)
                                    if (current_probability(y,x) < pHealthy_infected_byInfectedSick)
                                        boardNext(y,x) = app.INFECTED;
                                    elseif (current_probability(y,x) < pHealthy_infected_byInfectedSick+pHealthy_quarantine_byInfectedSick)
                                        boardNext(y,x) = app.IN_QUARANTINE;
                                    end
                                else
                                    if ((countNeighbours(app,board, app.INFECTED, y, x) > 8) && (current_probability(y,x) < 2*pHealthy_infected_byInfected))
                                        boardNext(y,x) = app.INFECTED;
                                    elseif ((countNeighbours(app,board, app.INFECTED, y, x) > 0) && (current_probability(y,x) < pHealthy_infected_byInfected))
                                        boardNext(y,x) = app.INFECTED;
                                    end
                                end
                                
                            case app.IN_QUARANTINE
                                if (current_probability(y,x) < p(app.IN_QUARANTINE,app.HEALTHY))
                                    boardNext(y,x) = app.HEALTHY;
                                elseif (current_probability(y,x) < p(app.IN_QUARANTINE,app.HEALTHY)+p(app.IN_QUARANTINE,app.RECOVERED))
                                    boardNext(y,x) = app.RECOVERED;
                                elseif (current_probability(y,x) < p(app.IN_QUARANTINE,app.HEALTHY)+p(app.IN_QUARANTINE,app.RECOVERED)+p(app.IN_QUARANTINE, app.IN_HOSPITAL))
                                    boardNext(y,x) = app.IN_HOSPITAL;
                                end
                                
                            case app.IN_HOSPITAL
                                if (current_probability(y,x) < pHospitalDead)
                                    boardNext(y,x) = app.DEAD;
                                elseif (current_probability(y,x) < pHospitalDead+pHospitalRecovered)
                                    boardNext(y,x) = app.RECOVERED;
                                elseif (current_probability(y,x) < pHospitalDead+pHospitalRecovered + p(app.IN_HOSPITAL, app.HEALTHY))
                                    boardNext(y,x) = app.HEALTHY;
                                else
                                    boardNext(y,x) = app.IN_HOSPITAL;
                                end
                                
                            case app.INFECTED
                                if (current_probability(y,x) < p(app.INFECTED, app.INFECTED_SICK))
                                    boardNext(y,x) = app.INFECTED_SICK;
                                elseif (current_probability(y,x) < p(app.INFECTED, app.INFECTED_SICK)+p(app.INFECTED, app.RECOVERED))
                                    boardNext(y,x) = app.RECOVERED;
                                else
                                    boardNext(y,x) = app.INFECTED;
                                end
                                
                            case app.SICK
                                if (current_probability(y,x) < p(app.SICK, app.IN_QUARANTINE))
                                    boardNext(y,x) = app.IN_QUARANTINE;
                                elseif (current_probability(y,x) < p(app.SICK, app.IN_QUARANTINE)+p(app.SICK, app.HEALTHY))
                                    boardNext(y,x) = app.HEALTHY;
                                else
                                    boardNext(y,x) = app.SICK;
                                end
                                
                            case app.INFECTED_SICK
                                if (current_probability(y,x) < p(app.INFECTED_SICK, app.IN_QUARANTINE))
                                    boardNext(y,x) = app.IN_QUARANTINE;
                                elseif (current_probability(y,x) < p(app.INFECTED_SICK, app.IN_QUARANTINE)+p(app.INFECTED_SICK, app.IN_HOSPITAL))
                                    boardNext(y,x) = app.IN_HOSPITAL;
                                elseif (current_probability(y,x) < p(app.INFECTED_SICK, app.IN_QUARANTINE)+p(app.INFECTED_SICK, app.IN_HOSPITAL)+p(app.INFECTED_SICK, app.RECOVERED))
                                    boardNext(y,x) = app.RECOVERED;
                                elseif (current_probability(y,x) < p(app.INFECTED_SICK, app.IN_QUARANTINE)+p(app.INFECTED_SICK, app.IN_HOSPITAL)+...
                                        p(app.INFECTED_SICK, app.RECOVERED) + p(app.INFECTED_SICK, app.DEAD))
                                    boardNext(y,x) = app.DEAD;
                                end
                                
                        end % switch
                    end % for x
                end % for y
                
                board = boardNext;
                
                
                if (app.CancelPressed == 1)
                    break;
                end
            end
           
            title(app.LivePlot, ['Gotowe! Zakończono w ' num2str(n) ' iteracji']);
            
            app.StatusLamp.Color = [0 1 0];
            app.StatusLabel.Text = 'Zakończono symulację...';
            app.StatusLamp.Enable = 1;
          
            app.StartButton.Enable = 1;
            app.StartButton.Text = 'Start';
            app.CancelButton.Enable = 0;
            app.GeneratePlotsButton.Enable = 1;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelPressed = 1;
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            drawPlots(app, app.CurrentIt); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SymulacjaWochUIFigure = uifigure;
            app.SymulacjaWochUIFigure.Name = 'Symulacja Włoch';
            app.StartButton = uibutton(app.SymulacjaWochUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RozmiarplanszyEditFieldLabel = uilabel(app.SymulacjaWochUIFigure);
            app.RozmiarplanszyEditFieldLabel.Text = 'Rozmiar planszy';
            app.BoardSize = uieditfield(app.SymulacjaWochUIFigure, 'numeric');
            app.BoardSize.Limits = [0 500];
            app.BoardSize.ValueDisplayFormat = '%.0f';
            app.BoardSize.Value = 200;
            app.LiczbaiteracjiEditFieldLabel = uilabel(app.SymulacjaWochUIFigure);
            app.LiczbaiteracjiEditFieldLabel.Text = 'Liczba iteracji';
            app.NumIterations = uieditfield(app.SymulacjaWochUIFigure, 'numeric');
            app.NumIterations.Limits = [0 500];
            app.NumIterations.ValueDisplayFormat = '%.0f';
            app.NumIterations.Value = 200;
            app.PublicProtection = uicheckbox(app.SymulacjaWochUIFigure);
            app.PublicProtection.Text = 'Ochrona zbiorowa';
            app.PublicProtection.Value = true;
            app.SelfProtection = uicheckbox(app.SymulacjaWochUIFigure);
            app.SelfProtection.Text = 'Ochrona indywidualna';
            app.SelfProtection.Value = true;
            app.Label1 = uilabel(app.SymulacjaWochUIFigure);
            app.Label1.Text = 'Prawdopodobieństwa dla pozostałych stanów:';
            app.CancelButton = uibutton(app.SymulacjaWochUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Anuluj';
            app.TabGroup = uitabgroup(app.SymulacjaWochUIFigure);
            app.BrakOchronyTab = uitab(app.TabGroup);
            app.BrakOchronyTab.Title = 'Brak Ochrony';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel = uilabel(app.BrakOchronyTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_none_infByInf = uieditfield(app.BrakOchronyTab, 'numeric');
            app.p_none_infByInf.Limits = [0 100];
            app.p_none_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_none_infByInf.Value = 40;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel = uilabel(app.BrakOchronyTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_none_infBySick = uieditfield(app.BrakOchronyTab, 'numeric');
            app.p_none_infBySick.Limits = [0 100];
            app.p_none_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_none_infBySick.Value = 60;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel = uilabel(app.BrakOchronyTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel.Text = 'Prawd. kwarantanny przez chorego';
            app.p_none_qBySick = uieditfield(app.BrakOchronyTab, 'numeric');
            app.p_none_qBySick.Limits = [0 100];
            app.p_none_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_none_qBySick.Value = 1;
            app.TylkopublTab = uitab(app.TabGroup);
            app.TylkopublTab.Title = 'Tylko publ.';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_2 = uilabel(app.TylkopublTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_2.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_pub_infByInf = uieditfield(app.TylkopublTab, 'numeric');
            app.p_pub_infByInf.Limits = [0 100];
            app.p_pub_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_pub_infByInf.Value = 30;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_2 = uilabel(app.TylkopublTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_2.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_pub_infBySick = uieditfield(app.TylkopublTab, 'numeric');
            app.p_pub_infBySick.Limits = [0 100];
            app.p_pub_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_pub_infBySick.Value = 40;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_2 = uilabel(app.TylkopublTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_2.Text = 'Prawd. kwarantanny przez chorego';
            app.p_pub_qBySick = uieditfield(app.TylkopublTab, 'numeric');
            app.p_pub_qBySick.Limits = [0 100];
            app.p_pub_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_pub_qBySick.Value = 50;
            app.TylkoindywidualnaTab = uitab(app.TabGroup);
            app.TylkoindywidualnaTab.Title = 'Tylko indywidualna';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_3 = uilabel(app.TylkoindywidualnaTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_3.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_self_infByInf = uieditfield(app.TylkoindywidualnaTab, 'numeric');
            app.p_self_infByInf.Limits = [0 100];
            app.p_self_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_self_infByInf.Value = 15;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_3 = uilabel(app.TylkoindywidualnaTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_3.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_self_infBySick = uieditfield(app.TylkoindywidualnaTab, 'numeric');
            app.p_self_infBySick.Limits = [0 100];
            app.p_self_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_self_infBySick.Value = 23;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_3 = uilabel(app.TylkoindywidualnaTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_3.Text = 'Prawd. kwarantanny przez chorego';
            app.p_self_qBySick = uieditfield(app.TylkoindywidualnaTab, 'numeric');
            app.p_self_qBySick.Limits = [0 100];
            app.p_self_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_self_qBySick.Value = 5;
            app.ObieTab = uitab(app.TabGroup);
            app.ObieTab.Title = 'Obie';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_4 = uilabel(app.ObieTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_4.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_both_infByInf = uieditfield(app.ObieTab, 'numeric');
            app.p_both_infByInf.Limits = [0 100];
            app.p_both_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_both_infByInf.Value = 12;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_4 = uilabel(app.ObieTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_4.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_both_infBySick = uieditfield(app.ObieTab, 'numeric');
            app.p_both_infBySick.Limits = [0 100];
            app.p_both_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_both_infBySick.Value = 19;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_4 = uilabel(app.ObieTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_4.Text = 'Prawd. kwarantanny przez chorego';
            app.p_both_qBySick = uieditfield(app.ObieTab, 'numeric');
            app.p_both_qBySick.Limits = [0 100];
            app.p_both_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_both_qBySick.Value = 70;
            app.PocztkowaliczbazaraonychLabel = uilabel(app.SymulacjaWochUIFigure);
            app.PocztkowaliczbazaraonychLabel.Text = 'Początkowa liczba zarażonych';
            app.StartInfectedCnt = uieditfield(app.SymulacjaWochUIFigure, 'numeric');
            app.StartInfectedCnt.Limits = [1 500];
            app.StartInfectedCnt.Value = 10;
            app.TabGroup2 = uitabgroup(app.SymulacjaWochUIFigure);
            app.WkwarantannieTab = uitab(app.TabGroup2);
            app.WkwarantannieTab.Title = 'W kwarantannie';
            app.PrawdbyciazdrowymLabel = uilabel(app.WkwarantannieTab);
            app.PrawdbyciazdrowymLabel.Text = 'Prawd. bycia zdrowym';
            app.p_quarantine_healthy = uieditfield(app.WkwarantannieTab, 'numeric');
            app.p_quarantine_healthy.Limits = [0 100];
            app.p_quarantine_healthy.ValueDisplayFormat = '%2.0f %%';
            app.p_quarantine_healthy.Value = 70;
            app.PrawdwyzdrowieniajelimaobjawyLabel = uilabel(app.WkwarantannieTab);
            app.PrawdwyzdrowieniajelimaobjawyLabel.Text = 'Prawd. wyzdrowienia (jeśli ma objawy)';
            app.p_quarantine_recovered = uieditfield(app.WkwarantannieTab, 'numeric');
            app.p_quarantine_recovered.Limits = [0 100];
            app.p_quarantine_recovered.ValueDisplayFormat = '%2.0f %%';
            app.p_quarantine_recovered.Value = 25;
            app.PrawdhospitalizacjiLabel = uilabel(app.WkwarantannieTab);
            app.PrawdhospitalizacjiLabel.Text = 'Prawd. hospitalizacji';
            app.p_quarantine_hospital = uieditfield(app.WkwarantannieTab, 'numeric');
            app.p_quarantine_hospital.Limits = [0 100];
            app.p_quarantine_hospital.ValueDisplayFormat = '%2.0f %%';
            app.p_quarantine_hospital.Value = 5;
            app.Label3 = uilabel(app.WkwarantannieTab);
            app.Label3.Text = 'Prawdopodobieństwa powinny sumować się do 100%';
            app.ZaraeniTab = uitab(app.TabGroup2);
            app.ZaraeniTab.Title = 'Zarażeni';
            app.PrawdwyzdrowieniaprzejsciabezobjawowegoLabel = uilabel(app.ZaraeniTab);
            app.PrawdwyzdrowieniaprzejsciabezobjawowegoLabel.Text = 'Prawd. wyzdrowienia / przejscia bezobjawowego';
            app.p_infected_recovered = uieditfield(app.ZaraeniTab, 'numeric');
            app.p_infected_recovered.Limits = [0 100];
            app.p_infected_recovered.ValueDisplayFormat = '%2.0f %%';
            app.p_infected_recovered.Value = 20;
            app.PrawdzachorowaniazobjawamiLabel = uilabel(app.ZaraeniTab);
            app.PrawdzachorowaniazobjawamiLabel.Text = 'Prawd. zachorowania (z objawami)';
            app.p_infected_infectedSick = uieditfield(app.ZaraeniTab, 'numeric');
            app.p_infected_infectedSick.Limits = [0 100];
            app.p_infected_infectedSick.ValueDisplayFormat = '%2.0f %%';
            app.p_infected_infectedSick.Value = 20;
            app.ChorzyTab = uitab(app.TabGroup2);
            app.ChorzyTab.Title = 'Chorzy';
            app.PrawdkwarantannyEditFieldLabel = uilabel(app.ChorzyTab);
            app.PrawdkwarantannyEditFieldLabel.Text = 'Prawd. kwarantanny';
            app.p_infectedSick_quarantine = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_quarantine.Limits = [0 100];
            app.p_infectedSick_quarantine.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_quarantine.Value = 40;
            app.PrawdhospitalizacjiEditFieldLabel = uilabel(app.ChorzyTab);
            app.PrawdhospitalizacjiEditFieldLabel.Text = 'Prawd. hospitalizacji';
            app.p_infectedSick_hospital = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_hospital.Limits = [0 100];
            app.p_infectedSick_hospital.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_hospital.Value = 20;
            app.PrawdwyzdrowienaLabel = uilabel(app.ChorzyTab);
            app.PrawdwyzdrowienaLabel.Text = 'Prawd. wyzdrowiena';
            app.p_infectedSick_recovered = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_recovered.Limits = [0 100];
            app.p_infectedSick_recovered.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_recovered.Value = 27;
            app.PrawdmierciEditFieldLabel = uilabel(app.ChorzyTab);
            app.PrawdmierciEditFieldLabel.Text = 'Prawd. śmierci';
            app.p_infectedSick_dead = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_dead.Limits = [0 100];
            app.p_infectedSick_dead.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_dead.Value = 3;
            app.HospitalizowaniTab = uitab(app.TabGroup2);
            app.HospitalizowaniTab.Title = 'Hospitalizowani';
            app.PrawdzetonieCOVID19EditFieldLabel = uilabel(app.HospitalizowaniTab);
            app.PrawdzetonieCOVID19EditFieldLabel.Text = 'Prawd. ze to nie COVID-19';
            app.p_hospital_healthy = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_healthy.Limits = [0 100];
            app.p_hospital_healthy.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_healthy.Value = 1;
            app.PrawdwyzdrowieniaEditField_2Label = uilabel(app.HospitalizowaniTab);
            app.PrawdwyzdrowieniaEditField_2Label.Text = 'Prawd. wyzdrowienia';
            app.p_hospital_recovered_lackOfVents = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_recovered_lackOfVents.Limits = [0 100];
            app.p_hospital_recovered_lackOfVents.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_recovered_lackOfVents.Value = 49;
            app.PrawdmierciEditField_3Label = uilabel(app.HospitalizowaniTab);
            app.PrawdmierciEditField_3Label.Text = 'Prawd. śmierci';
            app.p_hospital_dead_lackOfVents = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_dead_lackOfVents.Limits = [0 100];
            app.p_hospital_dead_lackOfVents.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_dead_lackOfVents.Value = 50;
            app.ChorzynacoinnegoTab = uitab(app.TabGroup2);
            app.ChorzynacoinnegoTab.Title = 'Chorzy na co innego';
            app.PrawdwyzdrowieniaLabel = uilabel(app.ChorzynacoinnegoTab);
            app.PrawdwyzdrowieniaLabel.Text = 'Prawd. wyzdrowienia';
            app.p_sick_healthy = uieditfield(app.ChorzynacoinnegoTab, 'numeric');
            app.p_sick_healthy.Limits = [0 100];
            app.p_sick_healthy.ValueDisplayFormat = '%2.0f %%';
            app.p_sick_healthy.Value = 94;
            app.PrawdkwarantannyLabel = uilabel(app.ChorzynacoinnegoTab);
            app.PrawdkwarantannyLabel.Text = 'Prawd. kwarantanny';
            app.p_sick_quarantine = uieditfield(app.ChorzynacoinnegoTab, 'numeric');
            app.p_sick_quarantine.Limits = [0 100];
            app.p_sick_quarantine.ValueDisplayFormat = '%2.0f %%';
            app.p_sick_quarantine.Value = 6;
            app.Label2 = uilabel(app.SymulacjaWochUIFigure);
            app.Label2.Text = 'Prawdopodobienstwa zarażenia się przez osoby zdrowe:';
            app.TabGroup3 = uitabgroup(app.SymulacjaWochUIFigure);
            app.LiveTab = uitab(app.TabGroup3);
            app.LiveTab.Title = 'Live';
            app.LivePlot = uiaxes(app.LiveTab);
            title(app.LivePlot, 'Title')
            xlabel(app.LivePlot, 'X')
            ylabel(app.LivePlot, 'Y')
            app.ZdrowyLamp_5Label = uilabel(app.LiveTab);
            app.ZdrowyLamp_5Label.Text = 'Zdrowy';
            app.ZdrowyLamp_5 = uilamp(app.LiveTab);
            app.ZaraonyLampLabel = uilabel(app.LiveTab);
            app.ZaraonyLampLabel.Text = 'Zarażony';
            app.ZaraonyLamp = uilamp(app.LiveTab);
            app.ChoryLampLabel = uilabel(app.LiveTab);
            app.ChoryLampLabel.Text = 'Chory';
            app.ChoryLamp = uilamp(app.LiveTab);
            app.HospitalizowanyLampLabel = uilabel(app.LiveTab);
            app.HospitalizowanyLampLabel.Text = 'Hospitalizowany';
            app.HospitalizowanyLamp = uilamp(app.LiveTab);
            app.ChorynieCov19LampLabel = uilabel(app.LiveTab);
            app.ChorynieCov19LampLabel.Text = 'Chory (nie Cov19)';
            app.ChorynieCov19Lamp = uilamp(app.LiveTab);
            app.WyzdrowiaLampLabel = uilabel(app.LiveTab);
            app.WyzdrowiaLampLabel.Text = 'Wyzdrowiał';
            app.WyzdrowiaLamp = uilamp(app.LiveTab);
            app.ZmarLampLabel = uilabel(app.LiveTab);
            app.ZmarLampLabel.Text = 'Zmarł';
            app.ZmarLamp = uilamp(app.LiveTab);
            app.KwarantannaLampLabel = uilabel(app.LiveTab);
            app.KwarantannaLampLabel.Text = 'Kwarantanna';
            app.KwarantannaLamp = uilamp(app.LiveTab);
            app.RozkadTab = uitab(app.TabGroup3);
            app.RozkadTab.Title = 'Rozkład';
            app.DistributionPlot = uiaxes(app.RozkadTab);
            title(app.DistributionPlot, 'Title')
            xlabel(app.DistributionPlot, 'X')
            ylabel(app.DistributionPlot, 'Y')
            app.GeneratePlotsButton = uibutton(app.SymulacjaWochUIFigure, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Enable = 'off';
            app.GeneratePlotsButton.Text = 'Generuj Wykresy';
            app.StatusLabel = uilabel(app.SymulacjaWochUIFigure);
            app.StatusLabel.Text = 'Symulacja nie rozpoczęta...';
            app.StatusLamp = uilamp(app.SymulacjaWochUIFigure);
            app.StatusLamp.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ItalyGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SymulacjaWochUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SymulacjaWochUIFigure)
        end
    end
end