classdef SeirGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SEIRModelUIFigure             matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        NEditFieldLabel               matlab.ui.control.Label
        NEditField                    matlab.ui.control.NumericEditField
        TotalpopulationNSIRLabel      matlab.ui.control.Label
        I_0EditFieldLabel             matlab.ui.control.Label
        I_0EditField                  matlab.ui.control.NumericEditField
        TEditFieldLabel               matlab.ui.control.Label
        TEditField                    matlab.ui.control.NumericEditField
        dtEditFieldLabel              matlab.ui.control.Label
        dtEditField                   matlab.ui.control.NumericEditField
        InitialnumberofinfectedLabel  matlab.ui.control.Label
        TotalsimulationdurationdaysLabel  matlab.ui.control.Label
        TimestephoursLabel            matlab.ui.control.Label
        HintChangeanyvalueplotwillberedrawnautomaticallyLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        NoprotectionTab               matlab.ui.container.Tab
        sigmaEditFieldLabel           matlab.ui.control.Label
        sigmaEditField                matlab.ui.control.NumericEditField
        deltaEditFieldLabel           matlab.ui.control.Label
        deltaEditField                matlab.ui.control.NumericEditField
        eta1EditFieldLabel            matlab.ui.control.Label
        eta1EditField                 matlab.ui.control.NumericEditField
        eta2EditFieldLabel            matlab.ui.control.Label
        eta2EditField                 matlab.ui.control.NumericEditField
        gamma1EditFieldLabel          matlab.ui.control.Label
        gamma1EditField               matlab.ui.control.NumericEditField
        gamma2EditFieldLabel          matlab.ui.control.Label
        gamma2EditField               matlab.ui.control.NumericEditField
        lambda1EditFieldLabel         matlab.ui.control.Label
        lambda1EditField              matlab.ui.control.NumericEditField
        lambda2EditFieldLabel         matlab.ui.control.Label
        lambda2EditField              matlab.ui.control.NumericEditField
        kappa1EditFieldLabel          matlab.ui.control.Label
        kappa1EditField               matlab.ui.control.NumericEditField
        kappa2EditFieldLabel          matlab.ui.control.Label
        kappa2EditField               matlab.ui.control.NumericEditField
        SelfprotectionTab             matlab.ui.container.Tab
        sigmaEditField_2Label         matlab.ui.control.Label
        sigmaEditField_2              matlab.ui.control.NumericEditField
        deltaEditField_2Label         matlab.ui.control.Label
        deltaEditField_2              matlab.ui.control.NumericEditField
        eta1EditField_2Label          matlab.ui.control.Label
        eta1EditField_2               matlab.ui.control.NumericEditField
        eta2EditField_2Label          matlab.ui.control.Label
        eta2EditField_2               matlab.ui.control.NumericEditField
        gamma1EditField_2Label        matlab.ui.control.Label
        gamma1EditField_2             matlab.ui.control.NumericEditField
        gamma2EditField_2Label        matlab.ui.control.Label
        gamma2EditField_2             matlab.ui.control.NumericEditField
        lambda1EditField_2Label       matlab.ui.control.Label
        lambda1EditField_2            matlab.ui.control.NumericEditField
        lambda2EditField_2Label       matlab.ui.control.Label
        lambda2EditField_2            matlab.ui.control.NumericEditField
        kappa1EditField_2Label        matlab.ui.control.Label
        kappa1EditField_2             matlab.ui.control.NumericEditField
        kappa2EditField_2Label        matlab.ui.control.Label
        kappa2EditField_2             matlab.ui.control.NumericEditField
        PublicprotectionTab           matlab.ui.container.Tab
        sigmaEditField_3Label         matlab.ui.control.Label
        sigmaEditField_3              matlab.ui.control.NumericEditField
        deltaEditField_3Label         matlab.ui.control.Label
        deltaEditField_3              matlab.ui.control.NumericEditField
        eta1EditField_3Label          matlab.ui.control.Label
        eta1EditField_3               matlab.ui.control.NumericEditField
        eta2EditField_3Label          matlab.ui.control.Label
        eta2EditField_3               matlab.ui.control.NumericEditField
        gamma1EditField_3Label        matlab.ui.control.Label
        gamma1EditField_3             matlab.ui.control.NumericEditField
        gamma2EditField_3Label        matlab.ui.control.Label
        gamma2EditField_3             matlab.ui.control.NumericEditField
        lambda1EditField_3Label       matlab.ui.control.Label
        lambda1EditField_3            matlab.ui.control.NumericEditField
        lambda2EditField_3Label       matlab.ui.control.Label
        lambda2EditField_3            matlab.ui.control.NumericEditField
        kappa1EditField_3Label        matlab.ui.control.Label
        kappa1EditField_3             matlab.ui.control.NumericEditField
        kappa2EditField_3Label        matlab.ui.control.Label
        kappa2EditField_3             matlab.ui.control.NumericEditField
        BothTab                       matlab.ui.container.Tab
        sigmaEditField_4Label         matlab.ui.control.Label
        sigmaEditField_4              matlab.ui.control.NumericEditField
        deltaEditField_4Label         matlab.ui.control.Label
        deltaEditField_4              matlab.ui.control.NumericEditField
        eta1EditField_4Label          matlab.ui.control.Label
        eta1EditField_4               matlab.ui.control.NumericEditField
        eta2EditField_4Label          matlab.ui.control.Label
        eta2EditField_4               matlab.ui.control.NumericEditField
        gamma1EditField_4Label        matlab.ui.control.Label
        gamma1EditField_4             matlab.ui.control.NumericEditField
        gamma2EditField_4Label        matlab.ui.control.Label
        gamma2EditField_4             matlab.ui.control.NumericEditField
        lambda1EditField_4Label       matlab.ui.control.Label
        lambda1EditField_4            matlab.ui.control.NumericEditField
        lambda2EditField_4Label       matlab.ui.control.Label
        lambda2EditField_4            matlab.ui.control.NumericEditField
        kappa1EditField_4Label        matlab.ui.control.Label
        kappa1EditField_4             matlab.ui.control.NumericEditField
        kappa2EditField_4Label        matlab.ui.control.Label
        kappa2EditField_4             matlab.ui.control.NumericEditField
        betaEditFieldLabel            matlab.ui.control.Label
        betaEditField                 matlab.ui.control.NumericEditField
        VentillatorsEditFieldLabel    matlab.ui.control.Label
        VentillatorsEditField         matlab.ui.control.NumericEditField
        SelfprotectionCheckBox        matlab.ui.control.CheckBox
        PublicprotectionCheckBox      matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function simulate(app)
            Npop = app.NEditField.Value; %6*10^7; % Total population N = S + I + R
            I1_0 = app.I_0EditField.Value; %10; % initial number of infected
            T = app.TEditField.Value; 300; % period of 300 days
            dt = app.dtEditField.Value/24; % time interval of 6 hours (1/4 of a day)
            
            isSelfProtection = app.SelfprotectionCheckBox.Value;
            isPublicProtection = app.PublicprotectionCheckBox.Value;
            ventilators = app.VentillatorsEditField.Value;
            
            if (isSelfProtection && isPublicProtection)
                sigma = app.sigmaEditField_4.Value; %  0.9;
                delta = app.deltaEditField_4.Value; % 0.2;
                eta1 = app.eta1EditField_4.Value;  % 0.05;
                eta2 = app.eta2EditField_4.Value; % 0.4;
                gamma1 = app.gamma1EditField_4.Value; % 0.2;
                gamma2 = app.gamma2EditField_4.Value; % 0.05;
                lambda1 = app.lambda1EditField_4.Value; % 0.6;
                lambda2 = app.lambda2EditField_4.Value;  % 0.8;
                kappa1 = app.kappa1EditField_4.Value; % 0.02;
                kappa2 = app.kappa2EditField_4.Value; % 0.3;
            elseif (~isSelfProtection && isPublicProtection)
                sigma = app.sigmaEditField_3.Value; %  0.9;
                delta = app.deltaEditField_3.Value; % 0.2;
                eta1 = app.eta1EditField_3.Value;  % 0.05;
                eta2 = app.eta2EditField_3.Value; % 0.4;
                gamma1 = app.gamma1EditField_3.Value; % 0.2;
                gamma2 = app.gamma2EditField_3.Value; % 0.05;
                lambda1 = app.lambda1EditField_3.Value; % 0.6;
                lambda2 = app.lambda2EditField_3.Value;  % 0.8;
                kappa1 = app.kappa1EditField_3.Value; % 0.02;
                kappa2 = app.kappa2EditField_3.Value; % 0.3;
            elseif (isSelfProtection && ~isPublicProtection)
                sigma = app.sigmaEditField_2.Value; %  0.9;
                delta = app.deltaEditField_2.Value; % 0.2;
                eta1 = app.eta1EditField_2.Value;  % 0.05;
                eta2 = app.eta2EditField_2.Value; % 0.4;
                gamma1 = app.gamma1EditField_2.Value; % 0.2;
                gamma2 = app.gamma2EditField_2.Value; % 0.05;
                lambda1 = app.lambda1EditField_2.Value; % 0.6;
                lambda2 = app.lambda2EditField_2.Value;  % 0.8;
                kappa1 = app.kappa1EditField_2.Value; % 0.02;
                kappa2 = app.kappa2EditField_2.Value; % 0.3;
            else
                sigma = app.sigmaEditField.Value; %  0.9;
                delta = app.deltaEditField.Value; % 0.2;
                eta1 = app.eta1EditField.Value;  % 0.05;
                eta2 = app.eta2EditField.Value; % 0.4;
                gamma1 = app.gamma1EditField.Value; % 0.2;
                gamma2 = app.gamma2EditField.Value; % 0.05;
                lambda1 = app.lambda1EditField.Value; % 0.6;
                lambda2 = app.lambda2EditField.Value;  % 0.8;
                kappa1 = app.kappa1EditField.Value; % 0.02;
                kappa2 = app.kappa2EditField.Value; % 0.3;
            end
            beta = app.betaEditField.Value; % Must be 1;
            
            % Calculate the model
            [S,E,I1,I2,Q,H,R,D] = sir_model(app,sigma,beta,delta,eta1,eta2,gamma1,gamma2,lambda1,lambda2,kappa1,kappa2,Npop,I1_0,ventilators,T,dt);
            
            % Plots that display the epidemic outbreak
            tt = 0:dt:T-dt;
            
            plot(tt,S,tt,I1+I2,tt,Q,tt,H,tt,R,tt,D,'Parent',app.UIAxes);
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes,'Days');
            ylabel(app.UIAxes,'Number of individuals');
            title(app.UIAxes, 'SEIR Model results');
            legend(app.UIAxes,'S','I','Q', 'H', 'R', 'D');
            
            
        end
        
        function [S,E,I1,I2,Q,H,R,D] = sir_model(app,sigma,beta,delta,eta1,eta2,gamma1,gamma2,lambda1,lambda2,kappa1,kappa2,Npop,I1_0,vents,T,dt)
            S = zeros(1,T/dt);
            E = zeros(1,T/dt);
            I1 = zeros(1,T/dt);
            I2 = zeros(1,T/dt);
            Q = zeros(1,T/dt);
            H = zeros(1,T/dt);
            R = zeros(1,T/dt);
            D = zeros(1,T/dt);
            
            S(1) = Npop;
            I1(1) = I1_0;
            
            for tt = 1:(T/dt)-1
                % Equations of the model
                dS = (-sigma*(I1(tt)+I2(tt))*S(tt)/(Npop)) * dt;
                dE = (sigma*(I1(tt)+I2(tt))*S(tt)/(Npop) - beta*E(tt)) * dt;
                dI1 = (beta*E(tt) - delta*I1(tt) - eta1*I1(tt)) * dt;
                dI2 = (delta*I1(tt) - eta2*I2(tt) - gamma1*I2(tt)) * dt;
                dQ = (eta1*I1(tt) + eta2*I2(tt) - gamma2*Q(tt) - lambda1*Q(tt) - kappa1*Q(tt)) * dt;
                dH = (gamma1*I2(tt) + gamma2*Q(tt) - lambda2*H(tt) - kappa2*H(tt)) * dt;
                dR = (lambda1*Q(tt) + lambda2*H(tt)) * dt;
                dD = (kappa1*Q(tt) + (2*(vents<H(tt)+1))*kappa2*H(tt)) * dt;
                S(tt+1) = S(tt) + dS;
                E(tt+1) = E(tt) + dE;
                I1(tt+1) = I1(tt) + dI1;
                I2(tt+1) = I2(tt) + dI2;
                Q(tt+1) = Q(tt) + dQ;
                H(tt+1) = H(tt) + dH;
                R(tt+1) = R(tt) + dR;
                D(tt+1) = D(tt) + dD;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            simulate(app)
        end

        % Value changed function: I_0EditField, NEditField, 
        % PublicprotectionCheckBox, SelfprotectionCheckBox, TEditField, 
        % VentillatorsEditField, betaEditField, deltaEditField, 
        % dtEditField, eta1EditField, eta2EditField, gamma1EditField, 
        % gamma2EditField, kappa1EditField, kappa2EditField, 
        % lambda1EditField, lambda2EditField, sigmaEditField
        function AnyValueChanged(app, event)
            simulate(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SEIRModelUIFigure = uifigure;
            app.SEIRModelUIFigure.Name = 'SEIR Model';
            app.UIAxes = uiaxes(app.SEIRModelUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NEditFieldLabel = uilabel(app.SEIRModelUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SEIRModelUIFigure, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NEditField.Value = 60000000;
            app.TotalpopulationNSIRLabel = uilabel(app.SEIRModelUIFigure);
            app.TotalpopulationNSIRLabel.Text = 'Total population N = S + I + R';
            app.I_0EditFieldLabel = uilabel(app.SEIRModelUIFigure);
            app.I_0EditFieldLabel.Text = 'I_0';
            app.I_0EditField = uieditfield(app.SEIRModelUIFigure, 'numeric');
            app.I_0EditField.Limits = [1 Inf];
            app.I_0EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.I_0EditField.Value = 10;
            app.TEditFieldLabel = uilabel(app.SEIRModelUIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.SEIRModelUIFigure, 'numeric');
            app.TEditField.Limits = [1 Inf];
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.TEditField.Value = 300;
            app.dtEditFieldLabel = uilabel(app.SEIRModelUIFigure);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.SEIRModelUIFigure, 'numeric');
            app.dtEditField.Limits = [1 Inf];
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.dtEditField.Value = 6;
            app.InitialnumberofinfectedLabel = uilabel(app.SEIRModelUIFigure);
            app.InitialnumberofinfectedLabel.Text = 'Initial number of infected';
            app.TotalsimulationdurationdaysLabel = uilabel(app.SEIRModelUIFigure);
            app.TotalsimulationdurationdaysLabel.Text = 'Total simulation duration [days]';
            app.TimestephoursLabel = uilabel(app.SEIRModelUIFigure);
            app.TimestephoursLabel.Text = 'Time step [hours]';
            app.HintChangeanyvalueplotwillberedrawnautomaticallyLabel = uilabel(app.SEIRModelUIFigure);
            app.HintChangeanyvalueplotwillberedrawnautomaticallyLabel.Text = 'Hint: Change any value - plot will be redrawn automatically';
            app.TabGroup = uitabgroup(app.SEIRModelUIFigure);
            app.NoprotectionTab = uitab(app.TabGroup);
            app.NoprotectionTab.Title = 'No protection';
            app.sigmaEditFieldLabel = uilabel(app.NoprotectionTab);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.sigmaEditField.Value = 0.9;
            app.deltaEditFieldLabel = uilabel(app.NoprotectionTab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.deltaEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.deltaEditField.Value = 0.02;
            app.eta1EditFieldLabel = uilabel(app.NoprotectionTab);
            app.eta1EditFieldLabel.Text = 'eta1';
            app.eta1EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.eta1EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.eta1EditField.Value = 0.01;
            app.eta2EditFieldLabel = uilabel(app.NoprotectionTab);
            app.eta2EditFieldLabel.Text = 'eta2';
            app.eta2EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.eta2EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.eta2EditField.Value = 0.2;
            app.gamma1EditFieldLabel = uilabel(app.NoprotectionTab);
            app.gamma1EditFieldLabel.Text = 'gamma1';
            app.gamma1EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.gamma1EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.gamma1EditField.Value = 0.2;
            app.gamma2EditFieldLabel = uilabel(app.NoprotectionTab);
            app.gamma2EditFieldLabel.Text = 'gamma2';
            app.gamma2EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.gamma2EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.gamma2EditField.Value = 0.05;
            app.lambda1EditFieldLabel = uilabel(app.NoprotectionTab);
            app.lambda1EditFieldLabel.Text = 'lambda1';
            app.lambda1EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.lambda1EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.lambda1EditField.Value = 0.6;
            app.lambda2EditFieldLabel = uilabel(app.NoprotectionTab);
            app.lambda2EditFieldLabel.Text = 'lambda2';
            app.lambda2EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.lambda2EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.lambda2EditField.Value = 0.8;
            app.kappa1EditFieldLabel = uilabel(app.NoprotectionTab);
            app.kappa1EditFieldLabel.Text = 'kappa1';
            app.kappa1EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.kappa1EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.kappa1EditField.Value = 0.02;
            app.kappa2EditFieldLabel = uilabel(app.NoprotectionTab);
            app.kappa2EditFieldLabel.Text = 'kappa2';
            app.kappa2EditField = uieditfield(app.NoprotectionTab, 'numeric');
            app.kappa2EditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.kappa2EditField.Value = 0.2;
            app.SelfprotectionTab = uitab(app.TabGroup);
            app.SelfprotectionTab.Title = 'Self protection';
            app.sigmaEditField_2Label = uilabel(app.SelfprotectionTab);
            app.sigmaEditField_2Label.Text = 'sigma';
            app.sigmaEditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.sigmaEditField_2.Value = 0.4;
            app.deltaEditField_2Label = uilabel(app.SelfprotectionTab);
            app.deltaEditField_2Label.Text = 'delta';
            app.deltaEditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.deltaEditField_2.Value = 0.02;
            app.eta1EditField_2Label = uilabel(app.SelfprotectionTab);
            app.eta1EditField_2Label.Text = 'eta1';
            app.eta1EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.eta1EditField_2.Value = 0.1;
            app.eta2EditField_2Label = uilabel(app.SelfprotectionTab);
            app.eta2EditField_2Label.Text = 'eta2';
            app.eta2EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.eta2EditField_2.Value = 0.3;
            app.gamma1EditField_2Label = uilabel(app.SelfprotectionTab);
            app.gamma1EditField_2Label.Text = 'gamma1';
            app.gamma1EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.gamma1EditField_2.Value = 0.2;
            app.gamma2EditField_2Label = uilabel(app.SelfprotectionTab);
            app.gamma2EditField_2Label.Text = 'gamma2';
            app.gamma2EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.gamma2EditField_2.Value = 0.05;
            app.lambda1EditField_2Label = uilabel(app.SelfprotectionTab);
            app.lambda1EditField_2Label.Text = 'lambda1';
            app.lambda1EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.lambda1EditField_2.Value = 0.6;
            app.lambda2EditField_2Label = uilabel(app.SelfprotectionTab);
            app.lambda2EditField_2Label.Text = 'lambda2';
            app.lambda2EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.lambda2EditField_2.Value = 0.8;
            app.kappa1EditField_2Label = uilabel(app.SelfprotectionTab);
            app.kappa1EditField_2Label.Text = 'kappa1';
            app.kappa1EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.kappa1EditField_2.Value = 0.02;
            app.kappa2EditField_2Label = uilabel(app.SelfprotectionTab);
            app.kappa2EditField_2Label.Text = 'kappa2';
            app.kappa2EditField_2 = uieditfield(app.SelfprotectionTab, 'numeric');
            app.kappa2EditField_2.Value = 0.2;
            app.PublicprotectionTab = uitab(app.TabGroup);
            app.PublicprotectionTab.Title = 'Public protection';
            app.sigmaEditField_3Label = uilabel(app.PublicprotectionTab);
            app.sigmaEditField_3Label.Text = 'sigma';
            app.sigmaEditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.sigmaEditField_3.Value = 0.7;
            app.deltaEditField_3Label = uilabel(app.PublicprotectionTab);
            app.deltaEditField_3Label.Text = 'delta';
            app.deltaEditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.deltaEditField_3.Value = 0.02;
            app.eta1EditField_3Label = uilabel(app.PublicprotectionTab);
            app.eta1EditField_3Label.Text = 'eta1';
            app.eta1EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.eta1EditField_3.Value = 0.2;
            app.eta2EditField_3Label = uilabel(app.PublicprotectionTab);
            app.eta2EditField_3Label.Text = 'eta2';
            app.eta2EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.eta2EditField_3.Value = 0.8;
            app.gamma1EditField_3Label = uilabel(app.PublicprotectionTab);
            app.gamma1EditField_3Label.Text = 'gamma1';
            app.gamma1EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.gamma1EditField_3.Value = 0.2;
            app.gamma2EditField_3Label = uilabel(app.PublicprotectionTab);
            app.gamma2EditField_3Label.Text = 'gamma2';
            app.gamma2EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.gamma2EditField_3.Value = 0.05;
            app.lambda1EditField_3Label = uilabel(app.PublicprotectionTab);
            app.lambda1EditField_3Label.Text = 'lambda1';
            app.lambda1EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.lambda1EditField_3.Value = 0.6;
            app.lambda2EditField_3Label = uilabel(app.PublicprotectionTab);
            app.lambda2EditField_3Label.Text = 'lambda2';
            app.lambda2EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.lambda2EditField_3.Value = 0.8;
            app.kappa1EditField_3Label = uilabel(app.PublicprotectionTab);
            app.kappa1EditField_3Label.Text = 'kappa1';
            app.kappa1EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.kappa1EditField_3.Value = 0.02;
            app.kappa2EditField_3Label = uilabel(app.PublicprotectionTab);
            app.kappa2EditField_3Label.Text = 'kappa2';
            app.kappa2EditField_3 = uieditfield(app.PublicprotectionTab, 'numeric');
            app.kappa2EditField_3.Value = 0.2;
            app.BothTab = uitab(app.TabGroup);
            app.BothTab.Title = 'Both';
            app.sigmaEditField_4Label = uilabel(app.BothTab);
            app.sigmaEditField_4Label.Text = 'sigma';
            app.sigmaEditField_4 = uieditfield(app.BothTab, 'numeric');
            app.sigmaEditField_4.Value = 0.35;
            app.deltaEditField_4Label = uilabel(app.BothTab);
            app.deltaEditField_4Label.Text = 'delta';
            app.deltaEditField_4 = uieditfield(app.BothTab, 'numeric');
            app.deltaEditField_4.Value = 0.02;
            app.eta1EditField_4Label = uilabel(app.BothTab);
            app.eta1EditField_4Label.Text = 'eta1';
            app.eta1EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.eta1EditField_4.Value = 0.2;
            app.eta2EditField_4Label = uilabel(app.BothTab);
            app.eta2EditField_4Label.Text = 'eta2';
            app.eta2EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.eta2EditField_4.Value = 0.8;
            app.gamma1EditField_4Label = uilabel(app.BothTab);
            app.gamma1EditField_4Label.Text = 'gamma1';
            app.gamma1EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.gamma1EditField_4.Value = 0.2;
            app.gamma2EditField_4Label = uilabel(app.BothTab);
            app.gamma2EditField_4Label.Text = 'gamma2';
            app.gamma2EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.gamma2EditField_4.Value = 0.05;
            app.lambda1EditField_4Label = uilabel(app.BothTab);
            app.lambda1EditField_4Label.Text = 'lambda1';
            app.lambda1EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.lambda1EditField_4.Value = 0.6;
            app.lambda2EditField_4Label = uilabel(app.BothTab);
            app.lambda2EditField_4Label.Text = 'lambda2';
            app.lambda2EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.lambda2EditField_4.Value = 0.8;
            app.kappa1EditField_4Label = uilabel(app.BothTab);
            app.kappa1EditField_4Label.Text = 'kappa1';
            app.kappa1EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.kappa1EditField_4.Value = 0.02;
            app.kappa2EditField_4Label = uilabel(app.BothTab);
            app.kappa2EditField_4Label.Text = 'kappa2';
            app.kappa2EditField_4 = uieditfield(app.BothTab, 'numeric');
            app.kappa2EditField_4.Value = 0.2;
            app.betaEditFieldLabel = uilabel(app.SEIRModelUIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.SEIRModelUIFigure, 'numeric');
            app.betaEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.betaEditField.Value = 0.1;
            app.VentillatorsEditFieldLabel = uilabel(app.SEIRModelUIFigure);
            app.VentillatorsEditFieldLabel.Text = 'Ventillators';
            app.VentillatorsEditField = uieditfield(app.SEIRModelUIFigure, 'numeric');
            app.VentillatorsEditField.Limits = [0 Inf];
            app.VentillatorsEditField.ValueDisplayFormat = '%.0f';
            app.VentillatorsEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.VentillatorsEditField.Value = 25000;
            app.SelfprotectionCheckBox = uicheckbox(app.SEIRModelUIFigure);
            app.SelfprotectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.SelfprotectionCheckBox.Text = 'Self protection';
            app.PublicprotectionCheckBox = uicheckbox(app.SEIRModelUIFigure);
            app.PublicprotectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.PublicprotectionCheckBox.Text = 'Public protection';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SeirGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SEIRModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SEIRModelUIFigure)
        end
    end
end