classdef belgia_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SymulacjaNiemiecUIFigure       matlab.ui.Figure
        LivePlot                       matlab.ui.control.UIAxes
        StartButton                    matlab.ui.control.Button
        RozmiarplanszyEditFieldLabel   matlab.ui.control.Label
        BoardSize                      matlab.ui.control.NumericEditField
        LiczbaiteracjiEditFieldLabel   matlab.ui.control.Label
        NumIterations                  matlab.ui.control.NumericEditField
        CancelButton                   matlab.ui.control.Button
        PocztkowaliczbazaraonychLabel  matlab.ui.control.Label
        StartInfectedCnt               matlab.ui.control.NumericEditField
        HealthyLampLabel               matlab.ui.control.Label
        HealthyLamp                    matlab.ui.control.Lamp
        InhospitalLampLabel            matlab.ui.control.Label
        InhospitalLamp                 matlab.ui.control.Lamp
        DeadLampLabel                  matlab.ui.control.Label
        DeadLamp                       matlab.ui.control.Lamp
        RecoveredLampLabel             matlab.ui.control.Label
        RecoveredLamp                  matlab.ui.control.Lamp
        InQuarantineLampLabel          matlab.ui.control.Label
        InQuarantineLamp               matlab.ui.control.Lamp
        InfectedLampLabel              matlab.ui.control.Label
        InfectedLamp                   matlab.ui.control.Lamp
        InfectedsickLampLabel          matlab.ui.control.Label
        InfectedsickLamp               matlab.ui.control.Lamp
        SickLampLabel                  matlab.ui.control.Label
        SickLamp                       matlab.ui.control.Lamp
        P1Label                        matlab.ui.control.Label
        P1a                            matlab.ui.control.NumericEditField
        P2Label                        matlab.ui.control.Label
        P2a                            matlab.ui.control.NumericEditField
        P3Label                        matlab.ui.control.Label
        P3a                            matlab.ui.control.NumericEditField
        DziennaliczbatestwLabel        matlab.ui.control.Label
        testy                          matlab.ui.control.NumericEditField
        P4Label                        matlab.ui.control.Label
        P4a                            matlab.ui.control.NumericEditField
        PrawdopodobistwaLabel          matlab.ui.control.Label
        P5Label                        matlab.ui.control.Label
        P5a                            matlab.ui.control.NumericEditField
        P6Label                        matlab.ui.control.Label
        P6a                            matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        sterowanie1Label               matlab.ui.control.Label
        P7a                            matlab.ui.control.NumericEditField
        sterowanie2Label               matlab.ui.control.Label
        P8a                            matlab.ui.control.NumericEditField
        SterowanieLabel                matlab.ui.control.Label
        stanyQ1Label                   matlab.ui.control.Label
        stanyQ2Label                   matlab.ui.control.Label
    end


    properties (Access = private)
        CancelPressed = 0 % Sets to 1 when user cancelled simulation
        CallingApp % Reference to main menu app
    end

    methods (Access = private)
% sąsiedztwo Moore'a o promieniu 3
% pierwsza macierz:
% 0-healthy, 1-in quarantine, 2-infected 3-sick, 4- infected and sick
% 5-in hospital, 6-recovered, 7-dead
% druga macierz: (każdy stan poprzedni jest lepszy od wcześniejszego)
% 0-no security measures, 1- infecting, 2-self-protecting,
% 3-protecting others, 4-organizing protection
% pierwsza ->jeśli 2,4 to zaraża
% druga -> jeśli 4 to pomaga 

        function sasiedzi = policz_Moore(~,plansza,x,y,P4)
    
    chorzy = 0;
    pomocni = 0;
    [w,k] = size(plansza(:,:,1));
    
    if x>4 && y>4 && x<w-3 && y<k-3 % w przeciwnym razie wyjdzie poza mazierz
        for i=x-3:x+3
            for j=y-3:y+3
                if i~=x || j~=y
                    if plansza(i,j,1)==2 || plansza(i,j,1)==4
                        chorzy = chorzy+1;
                        if plansza(i,j,2)==3 || plansza(i,j,2)==4
                            chorzy = chorzy-P4;  %jeśli są przzynajmniej "protecting others" mają mniejszą szanse, że zarażą innych
                        end
                    end
                    if plansza(i,j,2)==4 % jesli są "organizing protection" zmniejszają szanse że inni zaraża innych
                         pomocni=pomocni+1;
                    end
                end
            end
        end
    end
    sasiedzi=[chorzy,pomocni];
        
   end   
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.CallingApp = mainApp;
            
            title(app.LivePlot, 'Live plot');
            xlabel(app.LivePlot, []);
            ylabel(app.LivePlot, []);
            app.LivePlot.XAxis.TickLabels = {};
            app.LivePlot.YAxis.TickLabels = {};
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          app.CancelPressed = 0;
            
            rozmiar = app.BoardSize.Value;              % rozmiar planszy: NxN
            liczbaCykli = app.NumIterations.Value;          % ile iteracji zrobic
            infectedNum = app.StartInfectedCnt.Value;            % poczatkowa liczba chorych
      
%             ventilators_amount = rozmiar*rozmiar*app.ventCnt.Value/100; %0.0003125?
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%% PRAWDOPODOBIEŃSTWA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Formula:
            % prob_StanObecny_MozliwyStanKolejny[_wPoblizuZarazonego/wPoblizuChorego]
            % Dla kazdego StanObecny w sumie nie wiecej niz 100
            
            
                P1= app.P1a.Value; %szansa na zarażenie od 0-1000
                P2= app.P2a.Value; %szansa na ochronę przed zarażeniem 
                P3= app.P3a.Value; %szansa że selfprotecting+ jednak nie zachoruje
                P4=app.P4a.Value;  %jeśli są przzynajmniej "protecting others" mają mniejszą szanse, że zarażą innych
                szansa_na_zmadrzenie=5;
                szansa_na_zglupienie=10;
                liczba_testow=app.testy.Value; 


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ALGORYTM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                kwarantanna=zeros(rozmiar,rozmiar);
                inkubacja=zeros(rozmiar,rozmiar);
                czas_horoby=zeros(rozmiar,rozmiar);
                
                zycie = uint8(zeros(rozmiar,rozmiar)); % biezaca plansza zycia
                zycie(:,:,1)=int8(zeros(rozmiar,rozmiar));
                zycie(:,:,2)=int8(zeros(rozmiar,rozmiar));
                bb=0;
                
            
            STAT_healthy_cnt        = zeros(1, liczbaCykli);
            STAT_quarantine_cnt     = zeros(1, liczbaCykli);
            STAT_infected_cnt       = zeros(1, liczbaCykli);
            STAT_sick_cnt           = zeros(1, liczbaCykli);
            STAT_infected_sick_cnt  = zeros(1, liczbaCykli);
            STAT_in_hospital_cnt    = zeros(1, liczbaCykli);
            STAT_recovered_cnt      = zeros(1, liczbaCykli);
            STAT_dead_cnt           = zeros(1, liczbaCykli);
            
            % definicje stanów
            HEALTHY = 0;
            IN_QUARANTINE = 1;
            INFECTED = 2;
            SICK = 3;
            INFECTED_SICK = 4;
            IN_HOSPITAL = 5;
            RECOVERED = 6;
            DEAD = 7;
            
          
            app.StartButton.Enable = 0;
            app.StartButton.Text = 'Trwa symulacja...';
            app.CancelButton.Enable = 1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% MAIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for n=1:liczbaCykli
            
%---------b) Losowe dodawanie zarazonych na początku symulacji--------
    
                        if bb < infectedNum
                            go0=0;
                            if randi([0,100])<30
                                bb=bb+1;
                                go0=1;
                            end
                            if go0
                                xx=randi([3,rozmiar-3]);
                                yy=randi([3,rozmiar-3]);
                                if zycie(xx,yy,1)==0 
                                   zycie(xx,yy,1)=2;
                                   inkubacja(xx,yy)=14;
                                end  
                            end
                        end
%-----------------------------------------------------


                %--- KROK 1 ----------------------------------------------------
                % Wizualizacja i statystyki obecnej iteracji
                
                zycie_image = uint8(zeros(rozmiar, rozmiar, 3));
                
                STAT_healthy_cnt(n)         = sum(sum(zycie(:,:,1)==HEALTHY));
                STAT_quarantine_cnt(n)      = sum(sum(zycie(:,:,1)==IN_QUARANTINE));
                STAT_infected_cnt(n)        = sum(sum(zycie(:,:,1)==INFECTED));
                STAT_sick_cnt(n)            = sum(sum(zycie(:,:,1)==SICK));
                STAT_infected_sick_cnt(n)   = sum(sum(zycie(:,:,1)==INFECTED_SICK));
                STAT_in_hospital_cnt(n)     = sum(sum(zycie(:,:,1)==IN_HOSPITAL));
                STAT_recovered_cnt(n)       = sum(sum(zycie(:,:,1)==RECOVERED));
                STAT_dead_cnt(n)            = sum(sum(zycie(:,:,1)==DEAD));
                
                zycie_image(:,:,1) = (zycie(:,:,1)==HEALTHY)*255 + ...
                                     (zycie(:,:,1)==IN_QUARANTINE)*152 + ...
                                     (zycie(:,:,1)==INFECTED)*255 + ...
                                     (zycie(:,:,1)==SICK)*255 + ...
                                     (zycie(:,:,1)==INFECTED_SICK)*255 + ...
                                     (zycie(:,:,1)==IN_HOSPITAL)*0 + ...
                                     (zycie(:,:,1)==RECOVERED)*153 + ...
                                     (zycie(:,:,1)==DEAD)*0;
                
                zycie_image(:,:,2) = (zycie(:,:,1)==HEALTHY)*255 + ...
                                     (zycie(:,:,1)==IN_QUARANTINE)*51 + ...
                                     (zycie(:,:,1)==INFECTED)*128 + ...
                                     (zycie(:,:,1)==SICK)*255 + ...
                                     (zycie(:,:,1)==INFECTED_SICK)*0 + ...
                                     (zycie(:,:,1)==IN_HOSPITAL)*0 + ...
                                     (zycie(:,:,1)==RECOVERED)*255 +...
                                     (zycie(:,:,1)==DEAD)*0;
                                 
                zycie_image(:,:,3) = (zycie(:,:,1)==HEALTHY)*255 + ...
                                     (zycie(:,:,1)==IN_QUARANTINE)*255 + ...
                                     (zycie(:,:,1)==INFECTED)*0 + ...
                                     (zycie(:,:,1)==SICK)*0 + ...
                                     (zycie(:,:,1)==INFECTED_SICK)*0 + ...
                                     (zycie(:,:,1)==IN_HOSPITAL)*255 + ...
                                     (zycie(:,:,1)==RECOVERED)*255 +...
                                     (zycie(:,:,1)==DEAD)*0;
                
                title(app.LivePlot, ['Iteracja ' num2str(n)]);
                I = imshow(zycie_image, 'Parent', app.LivePlot, ...
                'XData', [1 app.LivePlot.Position(3)], ...
                'YData', [1 app.LivePlot.Position(4)]); drawnow;
                
                app.LivePlot.XLim = [0 I.XData(2)];
                app.LivePlot.YLim = [0 I.YData(2)];
                
                
%------Mentalność ludzi--------                     
    if(n==10) %po 10 dniach ludzie zaczęli uważać na siebie 
        szansa_na_zmadrzenie=10;
    elseif(n==15) %po 20 zaczeli jeszcze bardziej uważać  (wnioski z danych o belgi)
        szansa_na_zmadrzenie=25;
    end
    

zyciePom= zeros(rozmiar,rozmiar,2);      
%------Zdrowie ludzi--------                  
tic;
for w=4:rozmiar-3
for k=4:rozmiar-3
         
                        % 0 - healthy
            if zycie(w,k,1)==0  
                sasiedzi = policz_Moore(app,zycie,w,k,P4);
                szansa=sasiedzi(1)*P1-sasiedzi(2)*P2;  % zarażeni*P1 - chroniący*P2
                
                if szansa > randi([0 1000]) 
                    if zycie(w,k,2)==0
                        zyciePom(w,k,1)=2;
                        zyciePom(w,k,2)=1;
                        inkubacja(w,k)=7 + randi([-4,4]);
                    elseif  zycie(w,k,2)>1 % jeśli poziom mentalności (Q2) > 1 większa szansa że nie zarazi się
                        if randi([0 100]) > P3 % 10% szans że jednak nie zachoruje
                            zyciePom(w,k,1)=2;
                            inkubacja(w,k)=randi([3,14]); % czas inkubacji 3-14 dni
                        end
                

                    end
                end
            end 
% 1 - in quarantine tylko ludzie po testach + zakładam, że nik nie może tam zginąć
            if zycie(w,k,1)==1  
                if kwarantanna(w,k)==0
                    zyciePom(w,k,1)=6;
                else
                    kwarantanna(w,k)=kwarantanna(w,k)-1;
                    zyciePom(w,k,1)=1;
                end
            end
% 2 - infected
             if zycie(w,k,1)==2
                 if inkubacja(w,k)==0
                     if randi([0,100]) <app.P5a.Value %25 szans, że będzie ciężko chory i  pójdzie do szpitala
                         zyciePom(w,k,1)=5;
                         czas_horoby(w,k)=14 + randi([0,10]); % czas chroby 14-29 dni
                     elseif  randi([0,100]) < app.P6a.Value % (1-P5)*P6 szans że bedzie chory i będzie zarażał
                         zyciePom(w,k,1)=4;  
                         czas_horoby(w,k)=14 + randi([0,10]); % czas chroby 14-24 dni
                     else
                         zyciePom(w,k,1)=3; %1-P5-P6 szans że bedzie chory i nie będzie zarażał (będzie lezał w łóżku)
                         czas_horoby(w,k)=14 + randi([0,10]);
                     end
                 else
                     inkubacja(w,k)=inkubacja(w,k)-1;
                     zyciePom(w,k,1)=2;
                 end
             end
% 3 - sick 
            if zycie(w,k,1)==3
                if czas_horoby(w,k)==0
                    zyciePom(w,k,1)=0;    % znów zdrowy
                else
                    czas_horoby(w,k)=czas_horoby(w,k)-1;
                    zyciePom(w,k,1)=3;
                end
            end
% 4 - infected and sick
            if zycie(w,k,1)==4 
                if czas_horoby(w,k)==0
                    zyciePom(w,k,1)=6;    % wyzdrowiał
                else
                    czas_horoby(w,k)=czas_horoby(w,k)-1;
                    zyciePom(w,k,1)=4;
                end
            end
% 5 - in hospital
            if zycie(w,k,1)==5
                if czas_horoby(w,k)==0
                    if randi([0 100])<25  % 25% szans, że umrze
                        zyciePom(w,k,1)=7;
                    else
                        zyciePom(w,k,1)=6;
                    end
                else
                    czas_horoby(w,k)=czas_horoby(w,k)-1;
                    zyciePom(w,k,1)=5;
                end
            end
% 6 - recovered 
            if zycie(w,k,1)==6
                zyciePom(w,k,1)=6;
            end
% 7 - dead
            if zycie(w,k,1)==7
                zyciePom(w,k,1)=7;
            end
            
            
% ------Świadomość-----
% 0 - no security measures
            if zycie(w,k,2)==0
                if randi([0 100])<szansa_na_zmadrzenie
                    zyciePom(w,k,2)=2;
                else
                    zyciePom(w,k,2)=0;
                end
            end
% 1 - infecting
            if zycie(w,k,2)==1
                if randi([0 200])<szansa_na_zmadrzenie
                    zyciePom(w,k,2)=2;
                else
                    zyciePom(w,k,2)=1;
                end
            end
% 2 - self-protecting
            if zycie(w,k,2)==2
                if randi([0 1000])<szansa_na_zmadrzenie
                    zyciePom(w,k,2)=3;
                elseif randi([0 1000])<szansa_na_zglupienie
                    zyciePom(w,k,2)=1;
                else
                    zyciePom(w,k,2)=2;
                end
            end
% 3 - protecting others
            if zycie(w,k,2)==3
                if randi([0 1000])<szansa_na_zmadrzenie
                    zyciePom(w,k,2)=4;
                elseif randi([0 1000])<szansa_na_zglupienie
                    zyciePom(w,k,2)=2;
                else
                    zyciePom(w,k,2)=3;
                end
            end
% 4 - organizing protection
            if zycie(w,k,2)==4
                 if randi([0 1000])<szansa_na_zglupienie
                    zyciePom(w,k,2)=3;
                 else
                zyciePom(w,k,2)=4;
                 end
            end
            if zycie(w,k,1)==7
                zyciePom(w,k,2)=0; %matrwi nie dbają o zdrowie
            end
                    

end
end
toc;

% -------Sterowanie testami-------

%  --randomowe testy--
qq=1;
x=zeros(1,liczba_testow);
y=zeros(1,liczba_testow);
while qq <= liczba_testow
    new=[randi([3,rozmiar-3]),randi([3,rozmiar-3])];
    go=1;
    for i=1:qq
        if x(i)==new(1) && y(i)==new(2)
            qq=qq-1;
            go=0;
        end
    end
    
    if go
        x(qq)=new(1);
        y(qq)=new(2);
        if zycie(x(qq),y(qq),1)==7 || zycie(x(qq),y(qq),1)==6 || zycie(x(qq),y(qq),1)==5 || zycie(x(qq),y(qq),1)==1  % nie ma co sprawdzac martwych i wyzdrowiałych
            qq=qq-1;
        elseif zycie(x(qq),y(qq),1)==2  
            zyciePom(x(qq),y(qq),1)=1;
            kwarantanna(x(qq),y(qq))=inkubacja(x(qq),y(qq))+ 14 + randi([0,10]); % czas inkubacji + czas choroby
        elseif zycie(x(qq),y(qq),1)==4
            zyciePom(x(qq),y(qq))=1;
            kwarantanna(x(qq),y(qq))=czas_horoby(x(qq),y(qq));
        end  
    end
    qq=qq+1;
end

                
                %--- KROK 3 ----------------------------------------------------
                % przepisuje wartosci z zyciePom do zycie
                zycie= zyciePom;
                
                
                if (STAT_healthy_cnt(n) +  STAT_recovered_cnt(n) +  STAT_dead_cnt(n)== rozmiar*rozmiar && n>20)
                    break;
                end
                if (app.CancelPressed == 1)
                    break;
                end
            end
           
            
            it = 1:n;
    
figure(2);          
bar(it,STAT_healthy_cnt(1:n)); title('STATS healthy');
% saveas(gcf,'STAT_healthy_cnt.svg');
figure(3);          
bar(it,STAT_quarantine_cnt(1:n)), title('STATS in quarantine');
% saveas(gcf,'STAT_quarantine_cnt.svg');
figure(4);          
bar(it,STAT_infected_cnt(1:n)), title('STATS infected');
% saveas(gcf,'STAT_infected_cnt.svg');
figure(5);          
bar(it,STAT_sick_cnt(1:n)), title('STATS sick (but not COVID)');
% saveas(gcf,'STAT_sick_cnt.svg');
figure(6);  
bar(it,STAT_infected_sick_cnt(1:n)), title('STATS infected sick');
% saveas(gcf,'STAT_infected_sick_cnt.svg');
figure(7);          
bar(it,STAT_in_hospital_cnt(1:n)), title('STATS in hospital');
% saveas(gcf,'STAT_in_hospital_cnt.svg');
figure(8);          
bar(it,STAT_recovered_cnt(1:n)), title('STATS recovered');
% saveas(gcf,'STAT_recovered_cnt.svg');
figure(9);          
bar(it,STAT_dead_cnt(1:n)), title('STATS dead');
% saveas(gcf,'STAT_dead_cnt.svg');

figure(10);
imshow(zycie_image, 'InitialMagnification', 400);
text(2,2.5,['cykl ' num2str(n)]);
% saveas(gcf,'FINAL.svg');
            
            app.StartButton.Enable = 1;
            app.StartButton.Text = 'Start';
            app.CancelButton.Enable = 0;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelPressed = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SymulacjaNiemiecUIFigure = uifigure('Visible', 'off');
            app.SymulacjaNiemiecUIFigure.Name = 'Symulacja Niemiec';
            app.LivePlot = uiaxes(app.SymulacjaNiemiecUIFigure);
            title(app.LivePlot, 'Title')
            xlabel(app.LivePlot, 'X')
            ylabel(app.LivePlot, 'Y')
            app.StartButton = uibutton(app.SymulacjaNiemiecUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RozmiarplanszyEditFieldLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.RozmiarplanszyEditFieldLabel.Text = 'Rozmiar planszy';
            app.BoardSize = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.BoardSize.Limits = [0 500];
            app.BoardSize.ValueDisplayFormat = '%.0f';
            app.BoardSize.Value = 210;
            app.LiczbaiteracjiEditFieldLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.LiczbaiteracjiEditFieldLabel.Text = 'Liczba iteracji';
            app.NumIterations = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.NumIterations.Limits = [0 500];
            app.NumIterations.ValueDisplayFormat = '%.0f';
            app.NumIterations.Value = 200;
            app.CancelButton = uibutton(app.SymulacjaNiemiecUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Anuluj';
            app.PocztkowaliczbazaraonychLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.PocztkowaliczbazaraonychLabel.Text = 'Początkowa liczba zarażonych';
            app.StartInfectedCnt = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.StartInfectedCnt.Limits = [1 500];
            app.StartInfectedCnt.Tooltip = {'nie pojawaiją się od razu tylko dochodzą po koleji w przeciągu kilku iteracji'};
            app.StartInfectedCnt.Value = 3;
            app.HealthyLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.HealthyLampLabel.Text = 'Healthy';
            app.HealthyLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InhospitalLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InhospitalLampLabel.Text = 'In hospital';
            app.InhospitalLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.DeadLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.DeadLampLabel.Text = 'Dead';
            app.DeadLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.RecoveredLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.RecoveredLampLabel.Text = 'Recovered';
            app.RecoveredLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InQuarantineLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InQuarantineLampLabel.Text = 'In Quarantine';
            app.InQuarantineLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InfectedLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InfectedLampLabel.Text = 'Infected';
            app.InfectedLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InfectedsickLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InfectedsickLampLabel.Text = 'Infected & sick';
            app.InfectedsickLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.SickLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.SickLampLabel.Text = 'Sick';
            app.SickLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.P1Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.P1Label.Text = 'P1';
            app.P1a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P1a.Limits = [0 500];
            app.P1a.ValueDisplayFormat = '%.0f';
            app.P1a.Tooltip = {'szansz, że osoba w stanie (Q2) niższym niż 2 zarazi '};
            app.P1a.Value = 8;
            app.P2Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.P2Label.Text = 'P2';
            app.P2a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P2a.Limits = [0 500];
            app.P2a.ValueDisplayFormat = '%.0f';
            app.P2a.Tooltip = {'zmniejszenie szansy zarażenia za każda osobe w stanie (Q2) równym 4'; ''};
            app.P2a.Value = 2;
            app.P3Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.P3Label.Text = 'P3';
            app.P3a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P3a.Limits = [0 500];
            app.P3a.ValueDisplayFormat = '%.0f';
            app.P3a.Tooltip = {'dodatkową szansa, że josoba w stanie (Q2) równym 2 lub wyższym jednak się nie zarazi'};
            app.P3a.Value = 10;
            app.DziennaliczbatestwLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.DziennaliczbatestwLabel.Text = 'Dzienna liczba testów';
            app.testy = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.testy.Limits = [0 500];
            app.testy.ValueDisplayFormat = '%.0f';
            app.testy.Value = 50;
            app.P4Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.P4Label.Text = 'P4';
            app.P4a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P4a.Limits = [0 500];
            app.P4a.ValueDisplayFormat = '%.1f';
            app.P4a.Tooltip = {'zmniejszenie szansy, że osoba w stanie (Q2) równym 3 lub wyższym zarazi innych P=(P1*P4)'};
            app.P4a.Value = 0.5;
            app.PrawdopodobistwaLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.PrawdopodobistwaLabel.Tooltip = {'szansa na zarażenie(w zakersie 0 do 1000)='; '(ilość osób mogących zarazać i beących w stanie(Q2) 2 lub niższym)*P1 '; '+ilość osób mogących zarazać i beących w stanie(Q2) 3 lub wyższym)*(P1-P4)'; '-(ilość osób będących w stanie (Q2) 4)*P2'};
            app.PrawdopodobistwaLabel.Text = 'Prawdopodobięństwa:';
            app.P5Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.P5Label.Text = 'P5';
            app.P5a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P5a.Limits = [0 500];
            app.P5a.ValueDisplayFormat = '%.0f';
            app.P5a.Tooltip = {'szansa, że osoba bedzie ciężko chora i pójdzie do szpitala'};
            app.P5a.Value = 25;
            app.P6Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.P6Label.Text = 'P6';
            app.P6a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P6a.Limits = [0 500];
            app.P6a.ValueDisplayFormat = '%.0f';
            app.P6a.Tooltip = {'szansa równa (1-P5)*P6, że osoba bedzie chora i będzie dalej zarażała. Pozostała szansa to, że chory nie bedzie zarazał'};
            app.P6a.Value = 75;
            app.Label_4 = uilabel(app.SymulacjaNiemiecUIFigure);
            app.Label_4.Text = '';
            app.Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.Label.Text = '';
            app.sterowanie1Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.sterowanie1Label.Text = 'sterowanie 1';
            app.P7a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P7a.Limits = [0 500];
            app.P7a.ValueDisplayFormat = '%.0f';
            app.P7a.Tooltip = {'prawdopodąbięśtwo na przejscie do wyższego stanu Q2'};
            app.P7a.Value = 10;
            app.sterowanie2Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.sterowanie2Label.Text = 'sterowanie 2';
            app.P8a = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.P8a.Limits = [0 500];
            app.P8a.ValueDisplayFormat = '%.0f';
            app.P8a.Tooltip = {'prawdopodąbięśtwo na przejscie do niższego stanu Q2'};
            app.P8a.Value = 10;
            app.SterowanieLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.SterowanieLabel.Tooltip = {'każda osoba w każdym dniu ma szansę że przejdzie do stanu wyższago lub niższego w Q2'; 'prawdopodąbięśtwo na przejscie do wyższego stanu jest równe sterowaniu 1, a do niższego sterowaniu 2. '; 'Dodatkowo im wyższy stan tym mniejsze prawdopodobięśtwo, że dana osoba się tam dostanie'; '(przejście ze stanu 1 do 2 określone jest jako randi([0 100])<sterowanie 1, a ze stanu 3 do 4 jako randi([0 1000])<sterowaniu 2)'};
            app.SterowanieLabel.Text = 'Sterowanie:';
            app.stanyQ1Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.stanyQ1Label.Tooltip = {'0-healthy,'; ' 1-in quarantine, 2-infected,'; ' 3-sick,'; ' 4- infected and sick, '; '5-in hospital,'; ' 6-recovered,'; ' 7-dead'};
            app.stanyQ1Label.Text = 'stany Q1';
            app.stanyQ2Label = uilabel(app.SymulacjaNiemiecUIFigure);
            app.stanyQ2Label.Tooltip = {'0-no security measures, 1- infecting, 2-self-protecting, '; '3-protecting others, 4-organizing protection'};
            app.stanyQ2Label.Text = 'stany Q2';
            % Show the figure after all components are created
            app.SymulacjaNiemiecUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = belgia_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SymulacjaNiemiecUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SymulacjaNiemiecUIFigure)
        end
    end
end