classdef germanyGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SymulacjaNiemiecUIFigure        matlab.ui.Figure
        LivePlot                        matlab.ui.control.UIAxes
        StartButton                     matlab.ui.control.Button
        RozmiarplanszyEditFieldLabel    matlab.ui.control.Label
        BoardSize                       matlab.ui.control.NumericEditField
        LiczbaiteracjiEditFieldLabel    matlab.ui.control.Label
        NumIterations                   matlab.ui.control.NumericEditField
        PublicProtection                matlab.ui.control.CheckBox
        SelfProtection                  matlab.ui.control.CheckBox
        Label1                          matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BrakOchronyTab                  matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel  matlab.ui.control.Label
        p_none_infByInf                 matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel  matlab.ui.control.Label
        p_none_infBySick                matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel  matlab.ui.control.Label
        p_none_qBySick                  matlab.ui.control.NumericEditField
        TylkopublTab                    matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_2  matlab.ui.control.Label
        p_pub_infByInf                  matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_2  matlab.ui.control.Label
        p_pub_infBySick                 matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel_2  matlab.ui.control.Label
        p_pub_qBySick                   matlab.ui.control.NumericEditField
        TylkojednTab                    matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_3  matlab.ui.control.Label
        p_self_infByInf                 matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_3  matlab.ui.control.Label
        p_self_infBySick                matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel_3  matlab.ui.control.Label
        p_self_qBySick                  matlab.ui.control.NumericEditField
        ObieTab                         matlab.ui.container.Tab
        PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_4  matlab.ui.control.Label
        p_both_infByInf                 matlab.ui.control.NumericEditField
        PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_4  matlab.ui.control.Label
        p_both_infBySick                matlab.ui.control.NumericEditField
        PrawdkwarantannyprzezchoregoEditFieldLabel_4  matlab.ui.control.Label
        p_both_qBySick                  matlab.ui.control.NumericEditField
        PocztkowaliczbazaraonychLabel   matlab.ui.control.Label
        StartInfectedCnt                matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        WkwarantannieTab                matlab.ui.container.Tab
        PrawdzenicmuniejestEditFieldLabel  matlab.ui.control.Label
        p_quarantine_healthy            matlab.ui.control.NumericEditField
        PrawdzewyzdrowiejeEditFieldLabel  matlab.ui.control.Label
        p_quarantine_recovered          matlab.ui.control.NumericEditField
        PrawdzepojdziedoszpitalaEditFieldLabel  matlab.ui.control.Label
        p_quarantine_hospital           matlab.ui.control.NumericEditField
        Label3                          matlab.ui.control.Label
        ZarazeniTab                     matlab.ui.container.Tab
        PrawdzeprzejdziebezobjawowoiwyzdrowiejeEditFieldLabel  matlab.ui.control.Label
        p_infected_recovered            matlab.ui.control.NumericEditField
        PrawdzezachorujeEditFieldLabel  matlab.ui.control.Label
        p_infected_infectedSick         matlab.ui.control.NumericEditField
        ChorzyTab                       matlab.ui.container.Tab
        Label4                          matlab.ui.control.Label
        PrawdkwarantannyEditFieldLabel  matlab.ui.control.Label
        p_infectedSick_quarantine       matlab.ui.control.NumericEditField
        PrawdhospitalizacjiEditFieldLabel  matlab.ui.control.Label
        p_infectedSick_hospital         matlab.ui.control.NumericEditField
        PrawdzewyzdrowiejeEditField_2Label  matlab.ui.control.Label
        p_infectedSick_recovered        matlab.ui.control.NumericEditField
        PrawdmierciEditFieldLabel       matlab.ui.control.Label
        p_infectedSick_dead             matlab.ui.control.NumericEditField
        ChorzynacoinnegoTab             matlab.ui.container.Tab
        PrawdzebedziezdrowyEditFieldLabel  matlab.ui.control.Label
        p_sick_healthy                  matlab.ui.control.NumericEditField
        PrawdzebedzienakwarantannieEditFieldLabel  matlab.ui.control.Label
        p_sick_quarantine               matlab.ui.control.NumericEditField
        Label5                          matlab.ui.control.Label
        HospitalizowaniTab              matlab.ui.container.Tab
        PrawdzetonieCOVID19EditFieldLabel  matlab.ui.control.Label
        p_hospital_healthy              matlab.ui.control.NumericEditField
        PrawdwyzdrowieniaEditFieldLabel  matlab.ui.control.Label
        p_hospital_recovered_enoughVents  matlab.ui.control.NumericEditField
        PrawdmierciEditField_2Label     matlab.ui.control.Label
        p_hospital_dead_enoughVents     matlab.ui.control.NumericEditField
        PrawdwyzdrowieniaEditField_2Label  matlab.ui.control.Label
        p_hospital_recovered_lackOfVents  matlab.ui.control.NumericEditField
        PrawdmierciEditField_3Label     matlab.ui.control.Label
        p_hospital_dead_lackOfVents     matlab.ui.control.NumericEditField
        Label7                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        Label6                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        HealthyLampLabel                matlab.ui.control.Label
        HealthyLamp                     matlab.ui.control.Lamp
        InhospitalLampLabel             matlab.ui.control.Label
        InhospitalLamp                  matlab.ui.control.Lamp
        DeadLampLabel                   matlab.ui.control.Label
        DeadLamp                        matlab.ui.control.Lamp
        RecoveredLampLabel              matlab.ui.control.Label
        RecoveredLamp                   matlab.ui.control.Lamp
        InQuarantineLampLabel           matlab.ui.control.Label
        InQuarantineLamp                matlab.ui.control.Lamp
        InfectedLampLabel               matlab.ui.control.Label
        InfectedLamp                    matlab.ui.control.Lamp
        InfectedsickLampLabel           matlab.ui.control.Label
        InfectedsickLamp                matlab.ui.control.Lamp
        SickLampLabel                   matlab.ui.control.Label
        SickLamp                        matlab.ui.control.Lamp
        IlorespiratorwnapopulacjeLabel  matlab.ui.control.Label
        ventCnt                         matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CancelPressed = 0 % Sets to 1 when user cancelled simulation
        CallingApp % Reference to main menu app
    end

    methods (Access = private)
    
        function [states_sum] = checkNeighbors(~, zycieBoard, state, row, col)
            [height, width] = size(zycieBoard);
            if ((row > 2 && row < height-1) && (col > 2 && col < width-1))  
                neighbors = zycieBoard((row-2):(row+2), (col-2):(col+2));
                states_sum = sum(neighbors(:) == state);
            else
                states_sum = 0;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.CallingApp = mainApp;
            
            title(app.LivePlot, 'Live plot');
            xlabel(app.LivePlot, []);
            ylabel(app.LivePlot, []);
            app.LivePlot.XAxis.TickLabels = {};
            app.LivePlot.YAxis.TickLabels = {};
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                   
            app.CancelPressed = 0;
            
            rozmiar = app.BoardSize.Value;              % rozmiar planszy: NxN
            liczbaCykli = app.NumIterations.Value;          % ile iteracji zrobic
            infectedNum = app.StartInfectedCnt.Value;            % poczatkowa liczba chorych
            
            isSelfProtection = app.SelfProtection.Value;       % Czy zarzadzono osobista ochrone? (maseczki, rekawice...)      
            isPublicProtection = app.PublicProtection.Value;     % Czy zarzadzono ochrone publiczna? (dezynfekcja, ograniczenia przemieszczania się)
            ventilators_amount = rozmiar*rozmiar*app.ventCnt.Value/100; %0.0003125?
            
            rozmiar = rozmiar+4; % dodanie krawedzi ze wzgledu na sasiedztwo	   
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%% PRAWDOPODOBIEŃSTWA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Formula:
            % prob_StanObecny_MozliwyStanKolejny[_wPoblizuZarazonego/wPoblizuChorego]
            % Dla kazdego StanObecny w sumie nie wiecej niz 100
            
            prob_quarantine_healthy                     = app.p_quarantine_healthy.Value; %70;
            prob_quarantine_recovered                   = app.p_quarantine_recovered.Value; %25;
            prob_quarantine_hospital                    = app.p_quarantine_hospital.Value; %5;
            prob_sick_quarantine                        = app.p_sick_quarantine.Value; %5;
            prob_sick_healthy                           = app.p_sick_healthy.Value; %94;
            prob_infected_infectedSick                  = app.p_infected_infectedSick.Value; %20;
            prob_infected_recovered                     = app.p_infected_recovered.Value; %20;
            prob_infectedSick_quarantine                = app.p_infectedSick_quarantine.Value; %40;
            prob_infectedSick_hospital                  = app.p_infectedSick_hospital.Value; %20;
            prob_infectedSick_recovered                 = app.p_infectedSick_recovered.Value; %29;
            prob_infectedSick_dead                      = app.p_infectedSick_dead.Value; %1;
            prob_hospital_dead_enoughVents              = app.p_hospital_dead_enoughVents.Value; %20;
            prob_hospital_dead_lackOfVents              = app.p_hospital_dead_lackOfVents.Value; %50;
            prob_hospital_recovered_enoughVents         = app.p_hospital_recovered_enoughVents.Value; %79;
            prob_hospital_recovered_lackOfVents         = app.p_hospital_recovered_lackOfVents.Value; % 49;
            prob_hospital_healthy                       = app.p_hospital_healthy.Value; %1;
            
            if (~isSelfProtection && ~isPublicProtection)
                prob_healthy_infected_byInfected        = app.p_none_infByInf.Value; %40;
                prob_healthy_infected_byInfectedSick    = app.p_none_infBySick.Value; %60;
                prob_healthy_quarantine_byInfectedSick  = app.p_none_qBySick.Value; %1;
                
            elseif (isSelfProtection && ~isPublicProtection)
                prob_healthy_infected_byInfected        = app.p_self_infByInf.Value; %15;
                prob_healthy_infected_byInfectedSick    = app.p_self_infBySick.Value; %23;
                prob_healthy_quarantine_byInfectedSick  = app.p_self_qBySick.Value; %5;
                
            elseif (~isSelfProtection && isPublicProtection)
                prob_healthy_infected_byInfected        = app.p_pub_infByInf.Value; %30;
                prob_healthy_infected_byInfectedSick    = app.p_pub_infBySick.Value; %40;
                prob_healthy_quarantine_byInfectedSick  = app.p_pub_qBySick.Value; %50;
                
            elseif (isSelfProtection && isPublicProtection)
                prob_healthy_infected_byInfected        = app.p_both_infByInf.Value; %12;
                prob_healthy_infected_byInfectedSick    = app.p_both_infBySick.Value; %19;
                prob_healthy_quarantine_byInfectedSick  = app.p_both_qBySick.Value; %70;
            end
                
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ALGORYTM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            zycie = uint8(zeros(rozmiar,rozmiar)); % biezaca plansza zycia
            
            STAT_healthy_cnt        = zeros(1, liczbaCykli);
            STAT_quarantine_cnt     = zeros(1, liczbaCykli);
            STAT_infected_cnt       = zeros(1, liczbaCykli);
            STAT_sick_cnt           = zeros(1, liczbaCykli);
            STAT_infected_sick_cnt  = zeros(1, liczbaCykli);
            STAT_in_hospital_cnt    = zeros(1, liczbaCykli);
            STAT_recovered_cnt      = zeros(1, liczbaCykli);
            STAT_dead_cnt           = zeros(1, liczbaCykli);
            
            % definicje stanów
            HEALTHY = 0;
            IN_QUARANTINE = 1;
            INFECTED = 2;
            SICK = 3;
            INFECTED_SICK = 4;
            IN_HOSPITAL = 5;
            RECOVERED = 6;
            DEAD = 7;
            
            
            % x_start = randi(rozmiar);
            % y_start = randi(rozmiar);
            % 
            % zycie(y_start, x_start) = INFECTED;
            
            for n=1:infectedNum
                x_start = randi(rozmiar);
                y_start = randi(rozmiar);
                zycie(y_start, x_start) = INFECTED; 
            end
            
            app.StartButton.Enable = 0;
            app.StartButton.Text = 'Trwa symulacja...';
            app.CancelButton.Enable = 1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% MAIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for n=1:liczbaCykli
            
                %--- KROK 1 ----------------------------------------------------
                % Wizualizacja i statystyki obecnej iteracji
                
                zycie_image = uint8(zeros(rozmiar, rozmiar, 3));
                
                STAT_healthy_cnt(n)         = sum(zycie(:)==HEALTHY);
                STAT_quarantine_cnt(n)      = sum(zycie(:)==IN_QUARANTINE);
                STAT_infected_cnt(n)        = sum(zycie(:)==INFECTED);
                STAT_sick_cnt(n)            = sum(zycie(:)==SICK);
                STAT_infected_sick_cnt(n)   = sum(zycie(:)==INFECTED_SICK);
                STAT_in_hospital_cnt(n)     = sum(zycie(:)==IN_HOSPITAL);
                STAT_recovered_cnt(n)       = sum(zycie(:)==RECOVERED);
                STAT_dead_cnt(n)            = sum(zycie(:)==DEAD);
                
                zycie_image(:,:,1) = (zycie==HEALTHY)*255 + ...
                                     (zycie==IN_QUARANTINE)*152 + ...
                                     (zycie==INFECTED)*255 + ...
                                     (zycie==SICK)*255 + ...
                                     (zycie==INFECTED_SICK)*255 + ...
                                     (zycie==IN_HOSPITAL)*0 + ...
                                     (zycie==RECOVERED)*153 + ...
                                     (zycie==DEAD)*0;
                
                zycie_image(:,:,2) = (zycie==HEALTHY)*255 + ...
                                     (zycie==IN_QUARANTINE)*51 + ...
                                     (zycie==INFECTED)*128 + ...
                                     (zycie==SICK)*255 + ...
                                     (zycie==INFECTED_SICK)*0 + ...
                                     (zycie==IN_HOSPITAL)*0 + ...
                                     (zycie==RECOVERED)*255 +...
                                     (zycie==DEAD)*0;
                                 
                zycie_image(:,:,3) = (zycie==HEALTHY)*255 + ...
                                     (zycie==IN_QUARANTINE)*255 + ...
                                     (zycie==INFECTED)*0 + ...
                                     (zycie==SICK)*0 + ...
                                     (zycie==INFECTED_SICK)*0 + ...
                                     (zycie==IN_HOSPITAL)*255 + ...
                                     (zycie==RECOVERED)*255 +...
                                     (zycie==DEAD)*0;
                
                title(app.LivePlot, ['Iteracja ' num2str(n)]);
                I = imshow(zycie_image, 'Parent', app.LivePlot, ...
                'XData', [1 app.LivePlot.Position(3)], ...
                'YData', [1 app.LivePlot.Position(4)]); drawnow;
                
                app.LivePlot.XLim = [0 I.XData(2)];
                app.LivePlot.YLim = [0 I.YData(2)];
                    
                %--- KROK 2 ----------------------------------------------------
                % Obliczanie kolejnej iteracji
                if (STAT_infected_cnt(n) == 0)
                    break
                end
                
                if (STAT_in_hospital_cnt(n) > ventilators_amount)
                    prob_hospital_dead = prob_hospital_dead_lackOfVents;
                    prob_hospital_recovered = prob_hospital_recovered_lackOfVents;
                else
                    prob_hospital_dead = prob_hospital_dead_enoughVents;
                    prob_hospital_recovered = prob_hospital_recovered_enoughVents;
                end
                    
                zyciePom = zycie;
                prob_m = randi(101,rozmiar);
                
                tic;
                for w=1:rozmiar
                    for k=1:rozmiar
                        switch(zycie(w,k))
                            case RECOVERED
                                zyciePom(w,k) = RECOVERED;
                                
                            case DEAD
                                zyciePom(w,k) = DEAD;
                                
                            case HEALTHY
                            % Dla kazdej komorki HEALTHY obliczam liczbe sasiadow
                            % zarazonych i chorych, a od wyniku uzalezniam
                            % prawdopodobienstwo zarazenia sie
                                if (prob_m(w,k) == 70) % szum 1% - mala szansa na bycie chorym na cos innego
                                    zyciePom(w,k) = SICK;
                                end
                                if (checkNeighbors(app, zycie, INFECTED_SICK, w, k) > 0)
                                    if (prob_m(w,k) < prob_healthy_infected_byInfectedSick) 
                                        zyciePom(w,k) = INFECTED;
                                    elseif (prob_m(w,k) < prob_healthy_infected_byInfectedSick+prob_healthy_quarantine_byInfectedSick)
                                        zyciePom(w,k) = IN_QUARANTINE;
                                    end
                                else
                                    if ((checkNeighbors(app, zycie, INFECTED, w, k) > 8) && (prob_m(w,k) < 2*prob_healthy_infected_byInfected))
                                        zyciePom(w,k) = INFECTED;
                                    elseif ((checkNeighbors(app, zycie, INFECTED, w, k) > 0) && (prob_m(w,k) < prob_healthy_infected_byInfected))
                                        zyciePom(w,k) = INFECTED;
                                    end
                                end
                                    
                            case IN_QUARANTINE
                                if (prob_m(w,k) < prob_quarantine_healthy)
                                    zyciePom(w,k) = HEALTHY;
                                elseif (prob_m(w,k) < prob_quarantine_healthy+prob_quarantine_recovered)
                                    zyciePom(w,k) = RECOVERED;
                                elseif (prob_m(w,k) < prob_quarantine_healthy+prob_quarantine_recovered+prob_quarantine_hospital)
                                    zyciePom(w,k) = IN_HOSPITAL;
                                end
                                
                            case INFECTED
                                if (prob_m(w,k) < prob_infected_infectedSick)
                                    zyciePom(w,k) = INFECTED_SICK;
                                elseif (prob_m(w,k) < prob_infected_infectedSick+prob_infected_recovered)
                                    zyciePom(w,k) = RECOVERED;
                                else
                                    zyciePom(w,k) = INFECTED;
                                end
                                
                            case SICK
                                if (prob_m(w,k) < prob_sick_quarantine)
                                    zyciePom(w,k) = IN_QUARANTINE;
                                elseif (prob_m(w,k) < prob_sick_quarantine+prob_sick_healthy)
                                    zyciePom(w,k) = HEALTHY;
                                else
                                    zyciePom(w,k) = SICK;
                                end
                                
                            case INFECTED_SICK
                                if (prob_m(w,k) < prob_infectedSick_quarantine)
                                    zyciePom(w,k) = IN_QUARANTINE;
                                elseif (prob_m(w,k) < prob_infectedSick_quarantine+prob_infectedSick_hospital)
                                    zyciePom(w,k) = IN_HOSPITAL;
                                elseif (prob_m(w,k) < prob_infectedSick_quarantine+prob_infectedSick_hospital+prob_infectedSick_recovered)
                                    zyciePom(w,k) = RECOVERED;
                                elseif (prob_m(w,k) < prob_infectedSick_quarantine+prob_infectedSick_hospital+prob_infectedSick_recovered+prob_infectedSick_dead)
                                    zyciePom(w,k) = DEAD;
                                else
                                    zyciePom(w,k) = INFECTED_SICK;
                                end
                                
                            case IN_HOSPITAL
                                if (prob_m(w,k) < prob_hospital_dead)
                                    zyciePom(w,k) = DEAD;
                                elseif (prob_m(w,k) < prob_hospital_dead+prob_hospital_recovered)
                                    zyciePom(w,k) = RECOVERED;
                                elseif (prob_m(w,k) < prob_hospital_dead+prob_hospital_recovered+prob_hospital_healthy)
                                    zyciePom(w,k) = HEALTHY;
                                else
                                    zyciePom(w,k) = IN_HOSPITAL;
                                end
                        end     
                    end
                end
                toc;
                
                %--- KROK 3 ----------------------------------------------------
                % przepisuje wartosci z zyciePom do zycie
                zycie= zyciePom;
                
                
                if (app.CancelPressed == 1)
                    break;
                end
            end
           
            
            it = 1:n;
    
figure(2);          
bar(it,STAT_healthy_cnt(1:n)); title('STATS healthy');
% saveas(gcf,'STAT_healthy_cnt.svg');
figure(3);          
bar(it,STAT_quarantine_cnt(1:n)), title('STATS in quarantine');
% saveas(gcf,'STAT_quarantine_cnt.svg');
figure(4);          
bar(it,STAT_infected_cnt(1:n)), title('STATS infected');
% saveas(gcf,'STAT_infected_cnt.svg');
figure(5);          
bar(it,STAT_sick_cnt(1:n)), title('STATS sick (but not COVID)');
% saveas(gcf,'STAT_sick_cnt.svg');
figure(6);  
bar(it,STAT_infected_sick_cnt(1:n)), title('STATS infected sick');
% saveas(gcf,'STAT_infected_sick_cnt.svg');
figure(7);          
bar(it,STAT_in_hospital_cnt(1:n)), title('STATS in hospital');
% saveas(gcf,'STAT_in_hospital_cnt.svg');
figure(8);          
bar(it,STAT_recovered_cnt(1:n)), title('STATS recovered');
% saveas(gcf,'STAT_recovered_cnt.svg');
figure(9);          
bar(it,STAT_dead_cnt(1:n)), title('STATS dead');
% saveas(gcf,'STAT_dead_cnt.svg');

figure(10);
imshow(zycie_image, 'InitialMagnification', 400);
text(2,2.5,['cykl ' num2str(n)]);
% saveas(gcf,'FINAL.svg');
            
            app.StartButton.Enable = 1;
            app.StartButton.Text = 'Start';
            app.CancelButton.Enable = 0;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelPressed = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SymulacjaNiemiecUIFigure = uifigure;
            app.SymulacjaNiemiecUIFigure.Name = 'Symulacja Niemiec';
            app.LivePlot = uiaxes(app.SymulacjaNiemiecUIFigure);
            title(app.LivePlot, 'Title')
            xlabel(app.LivePlot, 'X')
            ylabel(app.LivePlot, 'Y')
            app.StartButton = uibutton(app.SymulacjaNiemiecUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RozmiarplanszyEditFieldLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.RozmiarplanszyEditFieldLabel.Text = 'Rozmiar planszy';
            app.BoardSize = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.BoardSize.Limits = [0 500];
            app.BoardSize.ValueDisplayFormat = '%.0f';
            app.BoardSize.Value = 200;
            app.LiczbaiteracjiEditFieldLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.LiczbaiteracjiEditFieldLabel.Text = 'Liczba iteracji';
            app.NumIterations = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.NumIterations.Limits = [0 500];
            app.NumIterations.ValueDisplayFormat = '%.0f';
            app.NumIterations.Value = 200;
            app.PublicProtection = uicheckbox(app.SymulacjaNiemiecUIFigure);
            app.PublicProtection.Text = 'Ochrona publiczna';
            app.PublicProtection.Value = true;
            app.SelfProtection = uicheckbox(app.SymulacjaNiemiecUIFigure);
            app.SelfProtection.Text = 'Ochrona jednostki';
            app.SelfProtection.Value = true;
            app.Label1 = uilabel(app.SymulacjaNiemiecUIFigure);
            app.Label1.Text = 'Prawdopodobienstwa dla poszczegolnych stanow:';
            app.CancelButton = uibutton(app.SymulacjaNiemiecUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Anuluj';
            app.TabGroup = uitabgroup(app.SymulacjaNiemiecUIFigure);
            app.BrakOchronyTab = uitab(app.TabGroup);
            app.BrakOchronyTab.Title = 'Brak Ochrony';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel = uilabel(app.BrakOchronyTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_none_infByInf = uieditfield(app.BrakOchronyTab, 'numeric');
            app.p_none_infByInf.Limits = [0 100];
            app.p_none_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_none_infByInf.Value = 40;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel = uilabel(app.BrakOchronyTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_none_infBySick = uieditfield(app.BrakOchronyTab, 'numeric');
            app.p_none_infBySick.Limits = [0 100];
            app.p_none_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_none_infBySick.Value = 60;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel = uilabel(app.BrakOchronyTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel.Text = 'Prawd. kwarantanny przez chorego';
            app.p_none_qBySick = uieditfield(app.BrakOchronyTab, 'numeric');
            app.p_none_qBySick.Limits = [0 100];
            app.p_none_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_none_qBySick.Value = 1;
            app.TylkopublTab = uitab(app.TabGroup);
            app.TylkopublTab.Title = 'Tylko publ.';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_2 = uilabel(app.TylkopublTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_2.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_pub_infByInf = uieditfield(app.TylkopublTab, 'numeric');
            app.p_pub_infByInf.Limits = [0 100];
            app.p_pub_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_pub_infByInf.Value = 30;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_2 = uilabel(app.TylkopublTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_2.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_pub_infBySick = uieditfield(app.TylkopublTab, 'numeric');
            app.p_pub_infBySick.Limits = [0 100];
            app.p_pub_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_pub_infBySick.Value = 40;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_2 = uilabel(app.TylkopublTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_2.Text = 'Prawd. kwarantanny przez chorego';
            app.p_pub_qBySick = uieditfield(app.TylkopublTab, 'numeric');
            app.p_pub_qBySick.Limits = [0 100];
            app.p_pub_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_pub_qBySick.Value = 50;
            app.TylkojednTab = uitab(app.TabGroup);
            app.TylkojednTab.Title = 'Tylko jedn';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_3 = uilabel(app.TylkojednTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_3.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_self_infByInf = uieditfield(app.TylkojednTab, 'numeric');
            app.p_self_infByInf.Limits = [0 100];
            app.p_self_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_self_infByInf.Value = 15;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_3 = uilabel(app.TylkojednTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_3.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_self_infBySick = uieditfield(app.TylkojednTab, 'numeric');
            app.p_self_infBySick.Limits = [0 100];
            app.p_self_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_self_infBySick.Value = 23;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_3 = uilabel(app.TylkojednTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_3.Text = 'Prawd. kwarantanny przez chorego';
            app.p_self_qBySick = uieditfield(app.TylkojednTab, 'numeric');
            app.p_self_qBySick.Limits = [0 100];
            app.p_self_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_self_qBySick.Value = 5;
            app.ObieTab = uitab(app.TabGroup);
            app.ObieTab.Title = 'Obie';
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_4 = uilabel(app.ObieTab);
            app.PrawdzaraeniaprzezbezobjawowegoEditFieldLabel_4.Text = 'Prawd. zarażenia przez bezobjawowego';
            app.p_both_infByInf = uieditfield(app.ObieTab, 'numeric');
            app.p_both_infByInf.Limits = [0 100];
            app.p_both_infByInf.ValueDisplayFormat = '%2.0f %%';
            app.p_both_infByInf.Value = 12;
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_4 = uilabel(app.ObieTab);
            app.PrawdzaraeniaprzezchoregonaCOVIDEditFieldLabel_4.Text = 'Prawd. zarażenia przez chorego na COVID';
            app.p_both_infBySick = uieditfield(app.ObieTab, 'numeric');
            app.p_both_infBySick.Limits = [0 100];
            app.p_both_infBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_both_infBySick.Value = 19;
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_4 = uilabel(app.ObieTab);
            app.PrawdkwarantannyprzezchoregoEditFieldLabel_4.Text = 'Prawd. kwarantanny przez chorego';
            app.p_both_qBySick = uieditfield(app.ObieTab, 'numeric');
            app.p_both_qBySick.Limits = [0 100];
            app.p_both_qBySick.ValueDisplayFormat = '%2.0f %%';
            app.p_both_qBySick.Value = 70;
            app.PocztkowaliczbazaraonychLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.PocztkowaliczbazaraonychLabel.Text = 'Początkowa liczba zarażonych';
            app.StartInfectedCnt = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.StartInfectedCnt.Limits = [1 500];
            app.StartInfectedCnt.Value = 3;
            app.TabGroup2 = uitabgroup(app.SymulacjaNiemiecUIFigure);
            app.WkwarantannieTab = uitab(app.TabGroup2);
            app.WkwarantannieTab.Title = 'W kwarantannie';
            app.PrawdzenicmuniejestEditFieldLabel = uilabel(app.WkwarantannieTab);
            app.PrawdzenicmuniejestEditFieldLabel.Text = 'Prawd. ze nic mu nie jest';
            app.p_quarantine_healthy = uieditfield(app.WkwarantannieTab, 'numeric');
            app.p_quarantine_healthy.Limits = [0 100];
            app.p_quarantine_healthy.ValueDisplayFormat = '%2.0f %%';
            app.p_quarantine_healthy.Value = 70;
            app.PrawdzewyzdrowiejeEditFieldLabel = uilabel(app.WkwarantannieTab);
            app.PrawdzewyzdrowiejeEditFieldLabel.Text = 'Prawd. ze wyzdrowieje';
            app.p_quarantine_recovered = uieditfield(app.WkwarantannieTab, 'numeric');
            app.p_quarantine_recovered.Limits = [0 100];
            app.p_quarantine_recovered.ValueDisplayFormat = '%2.0f %%';
            app.p_quarantine_recovered.Value = 25;
            app.PrawdzepojdziedoszpitalaEditFieldLabel = uilabel(app.WkwarantannieTab);
            app.PrawdzepojdziedoszpitalaEditFieldLabel.Text = 'Prawd. ze pojdzie do szpitala';
            app.p_quarantine_hospital = uieditfield(app.WkwarantannieTab, 'numeric');
            app.p_quarantine_hospital.Limits = [0 100];
            app.p_quarantine_hospital.ValueDisplayFormat = '%2.0f %%';
            app.p_quarantine_hospital.Value = 5;
            app.Label3 = uilabel(app.WkwarantannieTab);
            app.Label3.Text = 'Prawdopodobieństwa powinny sumować się do 100%';
            app.ZarazeniTab = uitab(app.TabGroup2);
            app.ZarazeniTab.Title = 'Zarazeni';
            app.PrawdzeprzejdziebezobjawowoiwyzdrowiejeEditFieldLabel = uilabel(app.ZarazeniTab);
            app.PrawdzeprzejdziebezobjawowoiwyzdrowiejeEditFieldLabel.Text = 'Prawd. ze przejdzie bezobjawowo i wyzdrowieje';
            app.p_infected_recovered = uieditfield(app.ZarazeniTab, 'numeric');
            app.p_infected_recovered.Limits = [0 100];
            app.p_infected_recovered.ValueDisplayFormat = '%2.0f %%';
            app.p_infected_recovered.Value = 20;
            app.PrawdzezachorujeEditFieldLabel = uilabel(app.ZarazeniTab);
            app.PrawdzezachorujeEditFieldLabel.Text = 'Prawd. ze zachoruje';
            app.p_infected_infectedSick = uieditfield(app.ZarazeniTab, 'numeric');
            app.p_infected_infectedSick.Limits = [0 100];
            app.p_infected_infectedSick.ValueDisplayFormat = '%2.0f %%';
            app.p_infected_infectedSick.Value = 20;
            app.ChorzyTab = uitab(app.TabGroup2);
            app.ChorzyTab.Title = 'Chorzy';
            app.Label4 = uilabel(app.ChorzyTab);
            app.Label4.Text = 'Prawdopodobieństwa powinny sumować się do 100%';
            app.PrawdkwarantannyEditFieldLabel = uilabel(app.ChorzyTab);
            app.PrawdkwarantannyEditFieldLabel.Text = 'Prawd. kwarantanny';
            app.p_infectedSick_quarantine = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_quarantine.Limits = [0 100];
            app.p_infectedSick_quarantine.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_quarantine.Value = 20;
            app.PrawdhospitalizacjiEditFieldLabel = uilabel(app.ChorzyTab);
            app.PrawdhospitalizacjiEditFieldLabel.Text = 'Prawd. hospitalizacji';
            app.p_infectedSick_hospital = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_hospital.Limits = [0 100];
            app.p_infectedSick_hospital.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_hospital.Value = 40;
            app.PrawdzewyzdrowiejeEditField_2Label = uilabel(app.ChorzyTab);
            app.PrawdzewyzdrowiejeEditField_2Label.Text = 'Prawd. ze wyzdrowieje';
            app.p_infectedSick_recovered = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_recovered.Limits = [0 100];
            app.p_infectedSick_recovered.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_recovered.Value = 29;
            app.PrawdmierciEditFieldLabel = uilabel(app.ChorzyTab);
            app.PrawdmierciEditFieldLabel.Text = 'Prawd. śmierci';
            app.p_infectedSick_dead = uieditfield(app.ChorzyTab, 'numeric');
            app.p_infectedSick_dead.Limits = [0 100];
            app.p_infectedSick_dead.ValueDisplayFormat = '%2.0f %%';
            app.p_infectedSick_dead.Value = 1;
            app.ChorzynacoinnegoTab = uitab(app.TabGroup2);
            app.ChorzynacoinnegoTab.Title = 'Chorzy na co innego';
            app.PrawdzebedziezdrowyEditFieldLabel = uilabel(app.ChorzynacoinnegoTab);
            app.PrawdzebedziezdrowyEditFieldLabel.Text = 'Prawd. ze bedzie zdrowy';
            app.p_sick_healthy = uieditfield(app.ChorzynacoinnegoTab, 'numeric');
            app.p_sick_healthy.Limits = [0 100];
            app.p_sick_healthy.ValueDisplayFormat = '%2.0f %%';
            app.p_sick_healthy.Value = 94;
            app.PrawdzebedzienakwarantannieEditFieldLabel = uilabel(app.ChorzynacoinnegoTab);
            app.PrawdzebedzienakwarantannieEditFieldLabel.Text = 'Prawd. ze bedzie na kwarantannie';
            app.p_sick_quarantine = uieditfield(app.ChorzynacoinnegoTab, 'numeric');
            app.p_sick_quarantine.Limits = [0 100];
            app.p_sick_quarantine.ValueDisplayFormat = '%2.0f %%';
            app.p_sick_quarantine.Value = 6;
            app.Label5 = uilabel(app.ChorzynacoinnegoTab);
            app.Label5.Text = 'Prawdopodobieństwa powinny sumować się do 100%';
            app.HospitalizowaniTab = uitab(app.TabGroup2);
            app.HospitalizowaniTab.Title = 'Hospitalizowani';
            app.PrawdzetonieCOVID19EditFieldLabel = uilabel(app.HospitalizowaniTab);
            app.PrawdzetonieCOVID19EditFieldLabel.Text = 'Prawd. ze to nie COVID-19';
            app.p_hospital_healthy = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_healthy.Limits = [0 100];
            app.p_hospital_healthy.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_healthy.Value = 1;
            app.PrawdwyzdrowieniaEditFieldLabel = uilabel(app.HospitalizowaniTab);
            app.PrawdwyzdrowieniaEditFieldLabel.Text = 'Prawd. wyzdrowienia';
            app.p_hospital_recovered_enoughVents = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_recovered_enoughVents.Limits = [0 100];
            app.p_hospital_recovered_enoughVents.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_recovered_enoughVents.Value = 79;
            app.PrawdmierciEditField_2Label = uilabel(app.HospitalizowaniTab);
            app.PrawdmierciEditField_2Label.Text = 'Prawd. śmierci';
            app.p_hospital_dead_enoughVents = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_dead_enoughVents.Limits = [0 100];
            app.p_hospital_dead_enoughVents.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_dead_enoughVents.Value = 20;
            app.PrawdwyzdrowieniaEditField_2Label = uilabel(app.HospitalizowaniTab);
            app.PrawdwyzdrowieniaEditField_2Label.Text = 'Prawd. wyzdrowienia';
            app.p_hospital_recovered_lackOfVents = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_recovered_lackOfVents.Limits = [0 100];
            app.p_hospital_recovered_lackOfVents.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_recovered_lackOfVents.Value = 49;
            app.PrawdmierciEditField_3Label = uilabel(app.HospitalizowaniTab);
            app.PrawdmierciEditField_3Label.Text = 'Prawd. śmierci';
            app.p_hospital_dead_lackOfVents = uieditfield(app.HospitalizowaniTab, 'numeric');
            app.p_hospital_dead_lackOfVents.Limits = [0 100];
            app.p_hospital_dead_lackOfVents.ValueDisplayFormat = '%2.0f %%';
            app.p_hospital_dead_lackOfVents.Value = 50;
            app.Label7 = uilabel(app.HospitalizowaniTab);
            app.Label7.Text = 'Gdy wystarcza respiratorów:';
            app.Label8 = uilabel(app.HospitalizowaniTab);
            app.Label8.Text = 'Gdy brak respiratorów:';
            app.Label6 = uilabel(app.HospitalizowaniTab);
            app.Label6.Text = 'Prawdopodobieństwa powinny sumować się do 100%';
            app.Label2 = uilabel(app.SymulacjaNiemiecUIFigure);
            app.Label2.Text = 'Prawdopodobienstwa zarazenia sie przez zdrowych:';
            app.HealthyLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.HealthyLampLabel.Text = 'Healthy';
            app.HealthyLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InhospitalLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InhospitalLampLabel.Text = 'In hospital';
            app.InhospitalLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.DeadLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.DeadLampLabel.Text = 'Dead';
            app.DeadLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.RecoveredLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.RecoveredLampLabel.Text = 'Recovered';
            app.RecoveredLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InQuarantineLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InQuarantineLampLabel.Text = 'In Quarantine';
            app.InQuarantineLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InfectedLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InfectedLampLabel.Text = 'Infected';
            app.InfectedLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.InfectedsickLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.InfectedsickLampLabel.Text = 'Infected & sick';
            app.InfectedsickLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.SickLampLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.SickLampLabel.Text = 'Sick';
            app.SickLamp = uilamp(app.SymulacjaNiemiecUIFigure);
            app.IlorespiratorwnapopulacjeLabel = uilabel(app.SymulacjaNiemiecUIFigure);
            app.IlorespiratorwnapopulacjeLabel.Text = 'Ilość respiratorów na populacje ';
            app.ventCnt = uieditfield(app.SymulacjaNiemiecUIFigure, 'numeric');
            app.ventCnt.ValueDisplayFormat = '%2.2f %%';
            app.ventCnt.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = germanyGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SymulacjaNiemiecUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SymulacjaNiemiecUIFigure)
        end
    end
end